classdef eprecorder_continuous_lab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EPRecorderContinuousLabUIFigure  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        TescsParametersButton       matlab.ui.control.Button
        ImportButton                matlab.ui.control.Button
        MergeDatasetsButton         matlab.ui.control.Button
        RemoveCurrentDatasetButton  matlab.ui.control.Button
        DatasetnameEditField        matlab.ui.control.EditField
        DatasetnameEditFieldLabel   matlab.ui.control.Label
        DatasetDropDown             matlab.ui.control.DropDown
        DatasetDropDownLabel        matlab.ui.control.Label
        DisplayTimeWidthEditField   matlab.ui.control.EditField
        DisplaytimemsLabel          matlab.ui.control.Label
        OpenButton                  matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        YLimitEditField             matlab.ui.control.EditField
        YLimitLabel                 matlab.ui.control.Label
        ActionDropDown              matlab.ui.control.DropDown
        ActionDropDownLabel         matlab.ui.control.Label
        InfoBox                     matlab.ui.control.Label
        CenterPanel                 matlab.ui.container.Panel
        RightPanel                  matlab.ui.container.Panel
        PreviousEpochButton         matlab.ui.control.Button
        NextEpochButton             matlab.ui.control.Button
        TabGroup                    matlab.ui.container.TabGroup
        PlayerTab                   matlab.ui.container.Tab
        PlaybackspeedKnob           matlab.ui.control.DiscreteKnob
        PlaybackspeedKnobLabel      matlab.ui.control.Label
        StopButton                  matlab.ui.control.Button
        PlayButton                  matlab.ui.control.Button
        FilterTab                   matlab.ui.container.Tab
        FilterZeroPhaseCheckBox     matlab.ui.control.CheckBox
        FilterChannelsEditField     matlab.ui.control.EditField
        ChannelsLabel               matlab.ui.control.Label
        FilterResponseButton        matlab.ui.control.Button
        ApplyFilterButton           matlab.ui.control.Button
        FilterTypeDropDown          matlab.ui.control.DropDown
        FiltertypeDropDownLabel     matlab.ui.control.Label
        FilterMethodDropDown        matlab.ui.control.DropDown
        MethodLabel                 matlab.ui.control.Label
        FilterOrderEditField        matlab.ui.control.EditField
        OrderLabel                  matlab.ui.control.Label
        FilterCufoffEditField       matlab.ui.control.EditField
        CufoffLabel                 matlab.ui.control.Label
        TriggerTab                  matlab.ui.container.Tab
        CreatetrialsButton          matlab.ui.control.Button
        CurrentEpochInfoLabel       matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    % Declaration for tags and references for UI objects.
    properties(Access=private)
        
        uiTags_axisInfo='axis-footer-info-label';% Displays info for axis data.

        uiTags_ImportDlgFigure='import-data-dlg';% Tag for import dialogue.

        TescsParametersDlgApp=[];% Reference to tescs param dlg app.

    end
    properties (Access = private)
        name='EP Recorder - Continuous Lab';% The base name of the app.
        allEPR={};% Array of all loaded EPR datasets
        EPR ={};% EPRecorder data structure
        currentSet=0;% The index of the current dataset
        channelAxes={};%Axes for each channel
        numEpochs=1; % Number of epochs
        numChans=0;% Number channels;
        currentEpochNumber=1;% The current epoch number
        displayTimeWidth=100; %Display window time width in ms.

        yLimit=[];% The ylimit of all axis that are channel data
    end

    
    methods (Access = private)
        function setDatasetName(app,name)
            % Set the name of the current dataset.
            % [INPUT]
            % name string: The new name.
            app.EPR.name=name;
        end
        function name=getDatasetName(app)
            % Get the name of the current dataset.
            % [OUTPUT]
            % name string: The name.
            name='newset';
            if(isfield(app.EPR,'name'))
                name=app.EPR.name;
            end
        end
        function name=channelNumber2Name(app,chan_num)
            % Convert a given channel number to name.
            % [INPUT]
            % chan_num: The channel number. If equals a number outside of
            % the actual signal channels count, it will be considered as
            % refering to all rows in data rather than just signal
            % channels. e.g if chan_num=0 then name='Time'.
            % [OUTPUT]
            % name string: channel name
            name='';
            if(chan_num==0)
                name='Time';
            elseif chan_num<=app.numChans
                name=app.EPR.channelNames{chan_num};
            elseif 1==chan_num-app.numChans
                name='Trigger';
            elseif 2==chan_num-app.numChans
                name='Stimulus code';
            end
        end

        function name=channelNumber2unit(app,chan_num)
            % Convert a given channel number to it data unit.
            % [INPUT]
            % chan_num: The channel number. 
            % [OUTPUT]
            % name string: channel data unit
            name='Abitr.';
           if chan_num<=app.numChans
                name=app.EPR.channelUnits{chan_num};
           end
        end
            
        function setDisplayTimeWidth(app,time_width)
            % Set the display time width property
            % [INPUT]
            % time_width float: The time length of display window in ms.
            app.displayTimeWidth=time_width;
            app.redrawEpoch();
        end
        function samples=getDisplayWidthSamples(app)
            % Get the current display width in samples.
            % [OUTPUT]
            % samples int: the display width in samples

            samples=round((app.displayTimeWidth/1000)*app.EPR.Fs);

            samples=max(samples,1);
        end
        function n_epochs=countDisplayEpochs(app)
            % Calculate the number of display width epochs for the current
            % display time width. 
            % [OUTPUT]
            % n_epochs int: number of epochs.
            data_samples=size(app.EPR.data,2);
            n_epochs=ceil(data_samples/app.getDisplayWidthSamples());
        end
        function prepareData(app)
            % Prepare the data for processing
            
            app.numEpochs=1;
            app.currentEpochNumber=1;
            app.numChans=length(app.EPR.channelNames);
            app.addFields()
        end

        function addFields(app)
            % Add any additional fields to the EPR structure.
            if(~isfield(app.EPR,'name'))
                app.EPR.name='noname';
            end

            % Add times field 
%             if(~isfield(app.EPR,'times'))
%                 app.EPR.times=(0:size(app.EPR.data,2)/app.EPR.Fs;
%             end
        end


        function showInfo(app)
            % Show main info for the EPR data structure

            filename=strrep(app.EPR.fn2,'\','/');
            filename=strsplit(filename,'/');
            filename=filename{end};
            info={
                ['Timepoint: ' app.EPR.timepoint]
                ['Tag: ' app.EPR.tag]
                ['Side|Cond|Param|Target: ',sprintf('%s|%s|%s|%s',app.EPR.side,app.EPR.condition,app.EPR.param,app.EPR.target)]
                ['Model: ' app.EPR.model]
                ['Model file: ' app.EPR.modelFile]
                ['Recording data folder: ' app.EPR.dataFolder]
                ['File: ' filename]
                ['Sample rate: ' mat2str(app.EPR.Fs)]
                ['Subject ID: ' mat2str(app.EPR.subjectId)]
                ['Section number: ' mat2str(app.EPR.sectionN)]
                ['Subsection number: ' mat2str(app.EPR.subsection)]
                ['Date: ' datestr(app.EPR.date)]
                ['EPR data version: ' app.EPR.version],
            };
            app.InfoBox.Text=info;

            app.DatasetnameEditField.Value=app.getDatasetName();
        end

        function visualise(app)
            % Visualise data
            app.buildAxes();
        end

        function clearAxes(app)
            % Clear visualisation axes.

            % We delete the Children and aspect garbage collector to remove
            % ani visual object that may be children of the Children
            delete(app.CenterPanel.Children);
        end
        
        function buildAxes(app)
            % Build the axes for data visualisation
            
            
            app.clearAxes();

            nchans=length(app.EPR.channelNames);
            ngridrows=nchans;
            ngridcols=1;
            centerPanelGrid=uigridlayout(app.CenterPanel,[ngridrows,ngridcols]);
            centerPanelGrid.Padding=[3,3,3,3];
            centerPanelGrid.ColumnSpacing=1;
            centerPanelGrid.RowSpacing=0;
            centerPanelGrid.Scrollable='on';

            for chan=1:nchans+2% We add 2 so that we can also plot triger and eventcode
                axisPanel=uipanel(centerPanelGrid);
                axisPanel.BorderType="none";
                axisPanelGrid=uigridlayout(axisPanel,[2,1]);
                axisPanelGrid.RowHeight = {'2x',10};
                axisPanelGrid.ColumnWidth = {'1x'};
                axisPanelGrid.Padding=[0,0,0,0];
                axisPanelGrid.ColumnSpacing=0;
                axisPanelGrid.RowSpacing=0;

                ax=uiaxes(axisPanelGrid);
                udata.footerPanel=app.buildAxisFooter(axisPanelGrid,chan);
                ax.UserData=udata;
                
                ax.NextPlot="replacechildren";
                %title(ax,[mat2str(chan),':',app.EPR.channelNames{chan}])
                ylabel(ax,app.channelNumber2unit(chan))
                if(chan>=nchans)
                    %xlabel(ax,'ms')
                else
                    ax.XAxis.Visible='off';
                    %set(ax,'XTickLabel',[]);
                end
                app.channelAxes{chan}=ax;
            end
            
        end

        function panel=buildAxisFooter(app,axisPanelGrid,chan_num)
            % Build the footer of an axis for an epoch channel.
            % [INPUT]
            % axisPanelGrid Grid: The grid contaning the axis for the given
            %   channel.
            % chan_num: The channel number for the axis
            % [OUTPUT]
            % panel uipanel: The uipanel the contaning the footer
            % 
            panel=uipanel(axisPanelGrid);
            panel.BorderType="none";
            panelGrid=uigridlayout(panel,[1,2]);
            panelGrid.Padding=[0,0,0,0];
            panelGrid.ColumnSpacing=0;
            panelGrid.RowSpacing=0;
            
            infoLabel=uilabel(panelGrid,"Text",'','Tag',app.uiTags_axisInfo);
            infoLabel.FontSize=7;
            infoLabel.Layout.Column=[1,2];
            infoLabel.HorizontalAlignment = 'center';

                     
        end

        function gotoEpoch(app,epoch_num)
            % Plot the data for epoch specified by the epoch number.
            % [INPUT]
            % epoch_num integer: The epoch number of the trial.
            % 
            app.currentEpochNumber=epoch_num;


            data=app.trial(app.currentEpochNumber);
            t=app.displayTimes(app.currentEpochNumber);
            for n=1:(length(app.channelAxes))
                ax=app.channelAxes{n};
                lineObj=plot(ax,t,data(n,:),"DisplayName",'Channel data');
                
                if(t(end)>t(1))
                    set(ax,'xlim',[t(1),t(end)]);
                end
                
                app.showTrigger(n);

                app.updateCurrentEpochAxisInfo(n);
            end
            app.updateCurrentEpochInfo();

        end
        function gotoNextEpoch(app,loop)
            % Plot the data for the epoch next to the curren one.
            % loop boolean: When true, will start from the first epoch when
            % the end is reached.
            if nargin<2
                loop=true;
            end

            current_num=app.currentEpochNumber;
            if(current_num==app.countDisplayEpochs())
                if(loop)
                    current_num=0;
                else
                    return
                end
            end
            current_num=current_num+1;
            app.gotoEpoch(current_num)
        end

        function gotoPreviousEpoch(app,loop)
            % Plot the data for the epoch previous to the curren one.
            % loop boolean: When true, will start from the last epoch when
            % the first is reached.
            if nargin<2
                loop=true;
            end

            current_num=app.currentEpochNumber;
            if(current_num==1)
                if(loop)
                    current_num=app.countDisplayEpochs();
                else
                    return
                end
            end
            current_num=current_num-1;
            app.gotoEpoch(current_num)
        end
        
        function redrawEpoch(app,epoch_num)
            % Plot the given or replot current epoch.
            % epoch_num int: The epoch number to plot. The default is the
            % current epoch.  
            if nargin<2
                epoch_num=app.currentEpochNumber;
            end
            app.gotoEpoch(epoch_num);
        end

        function showTrigger(app,chan_num)
            % Show the trigger on the axis for then given channel.
            % [INPUT]
            % chan_num integer: The channel number where the trigger is to
            % be plotted.
            ax=app.channelAxes{chan_num};
            original_nextplot=ax.NextPlot;
            ax.NextPlot="add";
            lim=ylim(ax);
            x=[0,0];% trigger is always only at zero as this is how data was triggered.
            y=lim/4;
            plot(ax,x,y,'-r',"DisplayName",'Trigger');
            ax.NextPlot=original_nextplot;
        end

        function updateCurrentEpochInfo(app)
            % Update the current display epoch's information.
            epoch_num=app.currentEpochNumber;

            
            info={
                sprintf('Event code: %g',app.eventCode(epoch_num))
                sprintf('Trigger time: %d samples',app.eventTrig(epoch_num))
                ['Notes: ' app.note(epoch_num)]
            };
            app.CurrentEpochInfoLabel.Text=info;
        end

        function updateCurrentEpochAxisInfo(app,chan_num)
            % Update the current display epoch's information for a given
            % channel. 
            % [INPUT]
            % chan_num: The channel numer for the axis to be updated.
            epoch_num=app.currentEpochNumber;

            ax=app.channelAxes{chan_num};
            footerPanel=ax.UserData.footerPanel;
            h = findobj(footerPanel,'Tag',app.uiTags_axisInfo);
            infoLabel=h(1);

            channel_name=app.channelNumber2Name(chan_num);


            info=[mat2str(chan_num),':',channel_name];
            infoLabel.Text=info;
        end

    end

    % Public methods
    methods(Access=public)
        function applyYLimit(app,limit)
            % Apply the given or current ylimit
            % [INPUT]
            % limit array<float>: 2-ele array. The defaylt is app.yLimit 
            if nargin<2
                limit=app.yLimit;
            end

            if(isempty(limit))
                return
            end

            for n=1:app.numChans
                app.setYLimit(limit,n);
            end
        end
        function setYLimit(app,ylimits,chan_num)
            % set the ylimit of the specified channel
            % [INPUT]
            % ylimits array: A 2-D array to set ylim.
            % chan_num integer: The channel number to update
            ax=app.channelAxes{chan_num};
            ylim(ax,ylimits);
        end
    end

    % Epoch helper methods ('epochs' for continuous data means 'segments')
    methods(Access=private)

        function is_epoched=isContinuous(app,EPR)
            % Check the data has not been epoched.
            % [INPUT]
            % EPR struct: EPR data structure. Default is the current
            %   dataset.
            % [OUTPUT]
            % is_epoched boolean: True if the data is not epoched.

            if nargin<2
                EPR=app.EPR;
            end

            is_epoched=true;
            if(isfield(EPR,'epochs') && ~isempty(EPR.epochs))
                is_epoched=false;
            end
        end


        function [start_idx,stop_idx]=displaySampleWindow(app,epoch_num)
            % The samples start and stop indexes for the given epoch
            % number.
            % [INPUT]
            % epoch_num integer: The epoch number of the trial.
            % [OUTPUT]
            % start_idx int: the starting index
            % stop_idex int: the stop index
            
            display_samples=app.getDisplayWidthSamples();
            
            start_idx=display_samples*(epoch_num-1) +1;
            stop_idx=display_samples*epoch_num;

            start_idx=max([start_idx,1]);
            
            data_samples=size(app.EPR.data,2);
            stop_idx=min([data_samples,stop_idx]);
        end
        function times=displayTimes(app,epoch_num)
            % The display time range for the given epoch number
            % [INPUT]
            % epoch_num integer: The epoch number of the trial.
            % [OUTPUT]
            % times array<float>: the times in ms for the given epoch number
            
            [start_idx,stop_idx]=app.displaySampleWindow(epoch_num);

            % Times
            %times=app.EPR.data(1,start_idx:stop_idx)*1000;
            
            % OR
            times=(start_idx:stop_idx)/app.EPR.Fs;
        end
        function data=trial(app,epoch_num)
            % Return the data for epoch specified by the epoch number. The
            % trigger and stimulus epochs are included.
            % [INPUT]
            % epoch_num integer: The epoch number of the trial.
            % [OUTPUT]
            % data array: The trial data. 3-d (chan x time).
            %chan_idx=2:app.numChans+1;
            [start_idx,stop_idx]=app.displaySampleWindow(epoch_num);
            data=app.EPR.data(2:end,start_idx:stop_idx);
        end

        function code=eventCode(app,epoch_num)
            % Return the event code for epoch specified by the epoch
            % number. 
            % [INPUT]
            % epoch_num integer: The epoch number of the trial.
            % [OUTPUT]
            % code string: The event code
            
            code=0; %TODO get all the code that fail within the display time.
            %code=app.EPR.epochs.codes(epoch_num);
        end

        function trig=eventTrig(app,epoch_num)
            % Return the event time for epoch specified by the epoch
            % number. 
            % [INPUT]
            % epoch_num integer: The epoch number of the trial.
            % [OUTPUT]
            % trig double: The event start time

            trig=0; %TODO get all the trigs that fail within the display time.
            %trig=app.EPR.epochs.trigs(epoch_num);
        end

        function s=note(app,epoch_num)
            % Return the note entered for epoch specified by the epoch
            % number. 
            % [INPUT]
            % epoch_num integer: The epoch number of the trial.
            % [OUTPUT]
            % data string: The note entered for the epoch
            s=''; %TODO get all the notes that fail within the display time.
            %note_e=app.EPR.epochs.notes{epoch_num};
            %if(~isempty(note_e))
            %    s=note_e{2};
            %end
        end

    end

    % Properties for player
    properties(Access=private)
        playerTimer=[];% Matlab timer object for the player
        displayTimeWidthCopy=[]; %A copy of the displayTimeWith
    end
    % Methods for epoch player
    methods(Access=private)
        function startPlayer(app,speed)
            % Play epoch starting from the current trial to the end
            % [INPUT]
            % speed int|double: The playback speed in seconds
            if nargin <2
                speed= app.PlaybackspeedKnob.Value;
            end

            app.displayTimeWidthCopy=app.displayTimeWidth;
            app.displayTimeWidth=0.01*app.EPR.Fs;
            
            t=timer('Period', speed, ...
                'StartDelay',speed,...
                'ExecutionMode','fixedRate', ...
                'TasksToExecute',10000);% We set an arbitrary high num here.
            t.TimerFcn=@playerTimerCallback;

            
            app.playerTimer=t;
            
            app.PlayButton.Enable="off";
            app.StopButton.Enable="on";
            start(app.playerTimer);

            function playerTimerCallback(timerObj,event)
                % Timer callback function 
                if(app.currentEpochNumber==app.countDisplayEpochs())
                    app.stopPlayer();
                end
                app.gotoNextEpoch(false);
            end
        end

        function stopPlayer(app)
            % Stop play back of trials
            stop(app.playerTimer);
            delete(app.playerTimer);
            app.PlayButton.Enable="on";
            app.StopButton.Enable="off";

            app.displayTimeWidth=app.displayTimeWidthCopy;
        end

        function is_playing=isPlaying(app)
            % Check if the player is playing
            % [OUTPUT]
            % is_playing boolean: True if the playing is playing.
            is_playing=false;
            if(~isempty(app.playerTimer))
                if(isvalid(app.playerTimer) && strcmp(app.playerTimer.Running,'on'))
                    is_playing=true;
                    return;
                end
            end
        end
            
    end

    % General methods
    methods(Access=private)
        function bootData(app,EPR)
            % Set boot up the app with initial data.
            % [INPUT]
            % EPR: The EPR data structure
            app.setCurrentDataset(app.addDataset(EPR));
            

            if(app.isContinuous())                
                app.gotoEpoch(1);
            else
                app.toEpochLab(EPR);
            end
        end
        function toEpochLab(app,EPR)
            % Send the give data to epoch lab.
            % [INPUT]
            % EPR: The EPR data structure
            eprecorder_epoch_lab(EPR);
        end
        function dataset_idx=addDataset(app,EPR)
            % Add a new dataset
            % [INPUT]
            % EPR: The EPR data structure
            % [OUTPUT]
            % dataset_idx int: The index of the new dataset in allEPR.
            app.allEPR{end+1}=EPR;
            dataset_idx=length(app.allEPR);

            app.updateDatasetDropdown();            
        end
        function removeDataset(app,dataset_idx)
            % The the current dataset
            % [INPUT]
            % dataset_idx int: The index of the dataset to remove
            
            app.currentSet=0;
            app.allEPR(dataset_idx)=[];

            app.updateDatasetDropdown(); 
            
            if(~isempty(app.allEPR))
                app.setCurrentDataset(1);
            else
                app.clearAxes();
            end
        
        end
        function removeCurrentDataset(app)
            % Add a new dataset
            if(app.currentSet~=0)
                app.removeDataset(app.currentSet);
            end
        end
        function setCurrentDataset(app,dataset_idx)
            % Set the current dataset
            % [INPUT]
            % dataset_idx int: The index of the dataset to set as current.
            app.syncCurrentset();

            app.currentSet=dataset_idx;
            app.EPR=app.allEPR{app.currentSet};

            app.appSubname(app.EPR.fn2);

            app.prepareData();
            app.showInfo();
            app.visualise();

            app.redrawEpoch(1);
            app.applyYLimit();

            % Sync with Dataset dropdown list
            app.DatasetDropDown.Value=app.currentSet;
        end

        function syncCurrentset(app)
            % Store the current dataset to allEPR.
            if(isempty(app.EPR) || app.currentSet==0)
                return;
            end
            app.allEPR{app.currentSet}=app.EPR;
        end
        
        function loadData(app,filename)
            % Load data given by filename
            % [INPUT]
            % filename string: EPR data filename

            EPRData=load(filename); 


            if app.isContinuous(EPRData.EPR)
                app.setCurrentDataset(app.addDataset(EPRData.EPR));
            else
                answer = questdlg(['The dataset is epoched. ' ...
                    'Do you want to open it in Epoch Lab?'], ...
	                'Open dataset', ...
	                'Yes','No','No');
                % Handle response
                switch answer
                    case 'Yes'
                        app.toEpochLab(EPRData.EPR);
                    case 'No'
                        warndlg('Epoched data should be opened with Epoch Lab','Open');
                end
            end
        end

        function openData(app,filename)
            % Open new EPR data
            % [INPUT]
            % filename string: EPR data filename/folder to load from.

            if nargin < 2
                filename=[];
            end

            default_fn=[];
            if ~isempty(filename)
                default_fn=filename;
                
            end

            if 2==exist(default_fn,'file')
                fn=default_fn;
            else
                if ~isempty(app.EPR)
                    default_fn=app.EPR.fn2;
                end
                [file,path] = uigetfile('.mat','Select EPR .mat file',default_fn);
                if(file==0)
                    return
                end
                fn=[path file];
                if(~exist(fn,'file'))
                    msgbox([fn, ' is not found'],'Cannot find file');
                    return;
                end
            end


            app.loadData(fn);
            
%            EPRData=load(fn); 
%             if app.isContinuous(EPRData.EPR)
%                 app.setCurrentDataset(app.addDataset(EPRData.EPR));
%             else
%                 answer = questdlg(['The dataset is epoched. ' ...
%                     'Do you want to open it in Epoch Lab?'], ...
% 	                'Open dataset', ...
% 	                'Yes','No','No');
%                 % Handle response
%                 switch answer
%                     case 'Yes'
%                         app.toEpochLab(EPRData.EPR);
%                     case 'No'
%                         warndlg('Epoched data should be opened with Epoch Lab','Open');
%                 end
%             end
            
        end

        function merge(app,EPR,EPR2)
            % Merge the data of EPR2 to EPR and use the result as the
            % current dataset. 
            % [INPUT]
            % EPR: The main EPR structure
            % EPR2: EPR data structure contain the data to be merged to EPR.
            [is_valid,msg]=eprecorder_validate_merge(EPR,EPR2);
            if(~is_valid)
                error(msg)
            end

            app.setCurrentDataset(app.addDataset(eprecorder_merge(EPR,EPR2)));
            
        end

        function mergeByDatasetIndex(app,idx1,idx2)
            % Merge the datasets with the given indexes as contained in
            % allEPR 
            % current dataset. 
            % [INPUT]
            % idx1 int: The index of the first base dataset.
            % idx2 int: The index of the source dataset.   
            app.merge(app.allEPR{idx1},app.allEPR{idx2});
        end

        function saveData(app,EPR)
            % Save the the given or current data
            % [INPUT]
            % EPR struct: The main EPR data structure. The default is the
            %   current dataset.
            is_current_dataset=false;
            if nargin<2
                EPR=app.EPR;
                is_current_dataset=true;
            end

            % Priotise using the filename of the current dataset.
            
            if isempty(app.EPR)
                default_fn=EPR.fn2;
            else
                default_fn=app.EPR.fn2;
            end

            %
            [file,path] = uiputfile('.mat','Select location and enter filename',default_fn);

            if(file==0)
                return
            end

            fn=[path file];
            if(exist(fn,'file'))
                answer=questdlg([fn, ' already axists. Do you want to overwrite it?'],'Confirm overwrite');
                if ~strcmp(answer,'Yes')
                    return
                end
            end
            
            EPR.fn2=fn;
            if is_current_dataset
                app.EPR.fn2=EPR.fn2;
            end

            save(EPR.fn2,'EPR');
        end

        function updateDatasetDropdown(app)
            % Update the dataset dropdown list.
            items_data=[];
            items={};
            for n=1:length(app.allEPR)
                name='newset';
                if(isfield(app.allEPR{n},'name'))
                    name=app.allEPR{n}.name;
                end
                items{n}=sprintf('%g. %s',n,name);
                items_data(n)=n;
            end
            app.DatasetDropDown.Items=items;
            app.DatasetDropDown.ItemsData=items_data;
        end

        function EPR_trials=convert2Trials(app,epoch_time_win,trigger_magnitude,trigger_primary,trigger_secondary_tau)
            % Convert continuous data to trials.
            % [INPUT]
            % EPR: EPR structure.
            % epoch_time_win array|[]: The time window in ms with which to
            %   epoch the data. A two element vector where the first element
            %   is the start time and the second is the end time. Default is
            %   to collect this a through dlg. The default can also be set
            %   by passing [].
            % trigger_magnitude double: Transition in the trigger channel that are
            %   considered as trigger. The default is 1. 
            % trigger_primary bool: see eprecorder_triggers() (i.e primary).
            %   The default is true. 
            % trigger_secondary_tau int: see eprecorder_triggers() (i.e
            %   secondary_tau). The default is 0. 
            % [OUTPUT]
            % EPR_trials struct|[]: EPR converted to frames. The value is
            %   empty on failiure. 
            dlg=false;
            if nargin<2 || isempty(epoch_time_win)
                dlg=true;
                epoch_time_win=[-100,100];
            end

            if nargin < 3
                dlg=true;
                trigger_magnitude=1;
            end

            if nargin < 4
                trigger_primary=1;
            end

            if nargin < 5
                dlg=true;
                trigger_secondary_tau=0;
            end


            EPR_trials=[];
            if dlg
                idxs=inputdlg({ ...
                    'Epoch start time (ms)', ...
                    'Epoch end time (ms)' ...
                    'Trigger magnitude' ...
                    'Secondary trigger tolerance(samples)'...
                    },'Create Trials',1,{mat2str(epoch_time_win(1)),mat2str(epoch_time_win(2)),mat2str(trigger_magnitude),mat2str(trigger_secondary_tau)});
                if(isempty(idxs) && length(idxs)~=3)
                    return;
                end
                epoch_time_win=[str2double(idxs{1}),str2double(idxs{2})];
                trigger_magnitude=str2double(idxs{3});
                trigger_secondary_tau=str2double(idxs{4});
            end
            EPR_trials=eprecorder_epoch(app.EPR,epoch_time_win, ...
                trigger_magnitude,trigger_primary,trigger_secondary_tau);
            

        end
        function appSubname(app,subname)
            % Set the subname of the app
            % [INPUT]
            % subname string: The subname.
            app.EPRecorderContinuousLabUIFigure.Name=[
                    app.name,...
                    ' - ',...
                    subname
                ];
        end

    end

    % Import data
    methods(Access=public)
        function importLegacy(app,EPRdata)
            % Import legacy EPR data.
            % [INPUT]
            % EPRdata struct: An older EPR data strcuture

            importer=eprecorder_importer;
            EPR_imported=importer.legacy(EPRdata);
            app.setCurrentDataset(app.addDataset(EPR_imported));
        end
        function importRaw(app,data,Fs,channel_names,channel_units,time_channel_index,trigger_channel_index,stimulus_code_channel_index)
            % Import continuous raw array data
            % [INPUT]
            % data array<double>|struct: Array or an EPR structure to be
            %   imported.
            % Fs double: Sample rate for raw data.
            % channel_names cell<string>: Channels names.
            % channel_units cell<string>: Channels units.
            % time_channel_index int: Index of channel in raw data
            %   that holds times for samples.
            % trigger_channel_index int: Index of channel in raw data
            %   that holds trigger. 
            % stimulus_code_channel_index int: Index of channel in raw
            %   data that holds stimulus code. 
            % 
            
            importer=eprecorder_importer;
            importer.Fs=Fs;
            importer.channelNames=channel_names;
            importer.channelUnits=channel_units;
            importer.timeChannelIndex=time_channel_index;
            importer.triggerChannelIndex=trigger_channel_index;
            importer.stimulusCodeChannelIndex=stimulus_code_channel_index;

            EPR_imported=importer.raw(data);
            app.setCurrentDataset(app.addDataset(EPR_imported));
        end
        function importDlg(app)
            % Open the dialog for importing data.

            BASE_WORKSPACE_RAW_DATA=[];

            fig=uifigure("Name",'Import', ...
                "Units","normalized",...
                "Tag",app.uiTags_ImportDlgFigure);


            tabgp = uitabgroup(fig,"Units","normalized", ...
                "Position",[.05 .05 .9 .9]);

            %% Raw tab
            rawTab = uitab(tabgp,'Title','Matlab array');        
            rawTabGrid=uigridlayout(rawTab,[9,2]);
            rawTabGrid.RowHeight = {22,44,22,22,22,22,22,22,22};
            
            uilabel(rawTabGrid,"HorizontalAlignment",'right', ...
                "Text","Variable name");
            VariableEdit=uieditfield(rawTabGrid,'text', ...
                "Value",'', ...
                "ValueChangingFcn",@LoadMatlabVariable,...
                "Tooltip","Name of the variable to import from base " + ...
                "workspace"...
                );

            VariableInfoLabel=uilabel(rawTabGrid,"Text",'No variable specified');
            VariableInfoLabel.Layout.Column=[1,2];
            VariableInfoLabel.HorizontalAlignment='center';
            VariableInfoLabel.WordWrap='on';
            VariableInfoLabel.BackgroundColor=[0.8 0.8,0.8];

            uilabel(rawTabGrid,"HorizontalAlignment",'right', ...
                "Text","Samplerate (Hz)");
            SampleRateEdit=uieditfield(rawTabGrid,'numeric', ...
                "Value",1,  "Tooltip","Samplerate"...
                ); 

            uilabel(rawTabGrid,"HorizontalAlignment",'right', ...
                "Text","Channel names");
            ChannelNamesEdit=uieditfield(rawTabGrid,'text', ...
                "Value",'', ...
                "Tooltip","Comma separated list of channel names",...
                "Placeholder",'Biceps,FDS,FDI'...
                );

            uilabel(rawTabGrid,"HorizontalAlignment",'right', ...
                "Text","Channel units");
            ChannelUnitsEdit=uieditfield(rawTabGrid,'text', ...
                "Value",'', ...
                "Tooltip","Comma separated list of channel units",...
                "Placeholder",'mV,mV,Kg'...
                );


            uilabel(rawTabGrid,"HorizontalAlignment",'right', ...
                "Text","Time channel index");
            TimeChannelIndexEdit=uieditfield(rawTabGrid,'text', ...
                "Value",'', ...
                "Tooltip","The index of time channel in data, if any.",...
                "Placeholder",'[]'...
                );

            uilabel(rawTabGrid,"HorizontalAlignment",'right', ...
                "Text","Trigger channel index");
            TriggerChannelIndexEdit=uieditfield(rawTabGrid,'text', ...
                "Value",'', ...
                "Tooltip","The index of trigger channel in data, if any.",...
                "Placeholder",'[]'...
                );

            uilabel(rawTabGrid,"HorizontalAlignment",'right', ...
                "Text","Stimulus code channel index");
            StimulusCodeChannelIndexEdit=uieditfield(rawTabGrid,'text', ...
                "Value",'', ...
                "Tooltip","The index of stimulus code channel in data, if any.",...
                "Placeholder",'[]'...
                );
 

            % 
            ImportRawButton=uibutton(rawTabGrid,"Text","Import", ...
                "ButtonPushedFcn",@ImportRawButtonPushed);
            ImportRawButton.Layout.Column=[1,2];

            %% Legacy tab
            legacyTab = uitab(tabgp,'Title','Legacy');
            legacyTabGrid=uigridlayout(legacyTab,[1,2]);
            legacyTabGrid.RowHeight = {22};
            ImportLegacyButton=uibutton(legacyTabGrid,"Text","Select EPR file", ...
                "ButtonPushedFcn",@ImportLegacyButtonPushed);
            ImportLegacyButton.Layout.Column=[1,2];
           

            %% Internal callbacks
            function LoadMatlabVariable(source,event)
                
                try 
                    % Variable may not exist in base workspace, hence the
                    % try statement.
                    disp(VariableEdit.Value );
                    temp_data=evalin('base',['' event.Value '']);
                    if not(isempty(temp_data)) && isa(temp_data,'double')
                        BASE_WORKSPACE_RAW_DATA=temp_data;
                    else
                        BASE_WORKSPACE_RAW_DATA=[];
                    end
                    
                    VariableInfoLabel.Text={
                            ['Variable: ',event.Value],
                            sprintf('It has %d Channels & %d samples',size(BASE_WORKSPACE_RAW_DATA))
                        };

                catch err
                    VariableInfoLabel.Text=err.message;
                    BASE_WORKSPACE_RAW_DATA=[];
                end

                nchans=size(BASE_WORKSPACE_RAW_DATA,1);
                if nchans~=length(strsplit(ChannelNamesEdit.Value,','))
                    channel_names=strip(sprintf('Channel %d,',1:nchans),',');
                    ChannelNamesEdit.Value=channel_names;
                end
                if nchans~=length(strsplit(ChannelUnitsEdit.Value,','))
                    channel_units=strip(repmat(['raw',','],1,nchans),',');
                    ChannelUnitsEdit.Value=channel_units;
                end  


            end

            function ImportRawButtonPushed(source,event)
                app.importRaw( ...
                    BASE_WORKSPACE_RAW_DATA,...
                    SampleRateEdit.Value,...
                    strsplit(ChannelNamesEdit.Value,','),...
                    strsplit(ChannelUnitsEdit.Value,','),...
                    str2num(TimeChannelIndexEdit.Value),...
                    str2num(TriggerChannelIndexEdit.Value),...
                    str2num(StimulusCodeChannelIndexEdit.Value)...
                );
                fig.delete;
                
            end
            function ImportLegacyButtonPushed(source,event)
                % Open Legacy EPR continuous data
                default_fn='';
                if ~isempty(app.EPR)
                    default_fn=app.EPR.fn2;
                end
                [file,path] = uigetfile('.mat','Select legacy EPR .mat file',default_fn);
                if(file==0)
                    return
                end
                fn=[path file];
                if(~exist(fn,'file'))
                    msgbox([fn, ' is not found'],'Cannot find file');
                    return;
                end
                
                EPRData=load(fn); 
                if app.isContinuous(EPRData.EPR)
                    app.importLegacy(EPRData.EPR);
                    fig.delete
                else
                    warndlg('Epoched data should be opened with Epoch Lab','Import');
                end
            end
        end
    end

    % Filtering
    methods(Access=private)
        function filter(app,channel_idxs,method,order,freqCutoff,filterType,zeroPhase,responseOnly)
            % Filter the specified channels
            % [INPUT]
            % channel_idxs array:The indexs of the channels to be filtered.
            %   Note that the first channel in EPR.data is time so it is
            %   skipped. Therefore, if channel_idxs=[1,3] then the filtered
            %   channels would be EPR.data([2,4],:), i.e we add 1 to
            %   channel_idxs to offset the first channel.
            % method string: filter method e.g b"utter"
            % order int: Filter order
            % freqCutoff int|2-element-vector: Frequency cut off in Hz e.g:[20,500]
            % filterType string: Filter type {'low','high','bandpass','stop'}
            % zeroPhase boolean: When true zero phase disturtion filtering
            %   is performed. The default is true.
            % responseOnly boolean: When true only filter response is shown
            % and no data is filtered.

            if nargin <7
                zeroPhase=true;
            end
            if nargin<8
                responseOnly=false;
            end

            f=eprecorder_filter(method,order,freqCutoff,app.EPR.Fs,filterType);
            actions{1}=sprintf('f=eprecorder_filter(''%s'',%g,%g,%g,''%s''); ',method,order,freqCutoff,app.EPR.Fs,filterType);
            [is_valid,msg]=f.validate();
            if(~is_valid)
                msgbox(msg,'Filter','modal');
                return;
            end
            f=f.design();
            actions{end+1}=sprintf('f=f.design();');
            
            if responseOnly
                f.show();
                return
            end
            for n=1:length(channel_idxs)
                chan=channel_idxs(n)+1;% Here we add 1 because the original channel 1 is skipped as it is time.
                app.EPR.data(chan,:)=f.filter(app.EPR.data(chan,:),zeroPhase);
                actions{end+1}=sprintf('f.filter(EPR.data(%d,:),%d); ',chan,double(zeroPhase));
            end
            
            app.EPR=eprecorder_add_history(app.EPR,cell2mat(actions),'Continuous lab filtering');
            app.redrawEpoch();
        end

        function filterWithGuiOptions(app,responseOnly)
            % Apply fitering using options selected in the gui
            % [INPUT]
            % responseOnly boolean: When true only filter response is shown
            % and no data is filtered.
            if nargin<2
                responseOnly=false;
            end
            channel_idxs = str2num(app.FilterChannelsEditField.Value);
            method = app.FilterMethodDropDown.Value;
            order = str2double(app.FilterOrderEditField.Value);
            freqCutoff = str2num(app.FilterCufoffEditField.Value);
            filterType = app.FilterTypeDropDown.Value;

            zeroPhase=app.FilterZeroPhaseCheckBox.Value;
            
            app.filter(channel_idxs,method,order,freqCutoff,filterType,zeroPhase,responseOnly);

        end
    end

    % TESCS 
    methods(Access=private)
        function openTescsParametersDlg(app)
            % Open TESCS parameters identification dialogue
            delete(app.TescsParametersDlgApp);
            app.TescsParametersDlgApp=eprecorder_tescs_parameters(app.allEPR);
        end
    end

    % Utilities
    methods(Access=private)
        function results=array_map(~,input_array,callback_fcn,with_idx)
            % Process every row of an array with the given callback and
            % return the concatenated result.
            % [INPUT]
            % input_array array: A 2-D array whos rows will be processed.
            % callbacl_fcn Function: The callback that will recieve each row.
            % with_idx boolean: When true the callback function will be
            %   called with two arguments, where the first the the current
            %   row of input_array and the second is the current index. When
            %   false, only the current row will be sent.
            % [OUTPUT]
            % results cellArray: The column array of results of processing each
            % row. Has the same number of rows as input_array.

            if nargin<4
                with_idx=false;
            end

            out_size=size(input_array,1);
            results=cell(out_size,1);
            for n=1:out_size
                if(with_idx)
                    results{n}=callback_fcn(input_array(n,:),n);
                else
                    results{n}=callback_fcn(input_array(n,:));
                end
            end
        end
    end
    
    % CallBack that handle dynamic component events
    methods(Access=private)
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EPR)
            
            if nargin>1
                if isstring(EPR) || ischar(EPR)
                    app.openData(EPR);
                elseif isstruct(EPR)
                    if eprecorder_has_epoch(EPR)                
                        app.toEpochLab(EPR);
                        app.delete;
                    else
                        disp('Initialising default data');
                        app.bootData(EPR);
                    end
                else
                    warning('Unknow input, no data was loaded');
                end
            end
        end

        % Value changed function: ActionDropDown
        function ActionDropDownValueChanged(app, event)
            value = app.ActionDropDown.Value;
            if(strcmpi('quick recruitment curve',value))
                eprecorder_recruitment_curve_quick(app.EPR)
            end
        end

        % Value changed function: YLimitEditField
        function YLimitEditFieldValueChanged(app, event)
            value = app.YLimitEditField.Value;
            y1=str2num(value);
            app.yLimit=y1;
            app.applyYLimit();
            
            
            % Sync trigger height with axis limit
            app.redrawEpoch();
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.saveData();
            
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            app.openData();
        end

        % Value changed function: PlaybackspeedKnob
        function PlaybackspeedKnobValueChanged(app, event)
            if(~app.isPlaying())
                return;
            end
            speed = app.PlaybackspeedKnob.Value;
            app.stopPlayer();
            app.startPlayer(speed);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stopPlayer();
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.startPlayer();
        end

        % Close request function: EPRecorderContinuousLabUIFigure
        function EPRecorderContinuousLabUIFigureCloseRequest(app, event)
            
            answer=questdlg('Are you sure you want to close the app','Confirm close');
            if ~strcmp(answer,'Yes')
                return
            end
            
            delete(app.playerTimer)
            delete(app.TescsParametersDlgApp);
            delete(app)
            
        end

        % Button pushed function: NextEpochButton
        function NextEpochButtonPushed(app, event)
            app.gotoNextEpoch();
        end

        % Button pushed function: PreviousEpochButton
        function PreviousEpochButtonPushed(app, event)
            app.gotoPreviousEpoch();
        end

        % Value changed function: DisplayTimeWidthEditField
        function DisplayTimeWidthEditFieldValueChanged(app, event)
            value = app.DisplayTimeWidthEditField.Value;
            app.setDisplayTimeWidth(str2double(value));
            
        end

        % Button pushed function: ApplyFilterButton
        function ApplyFilterButtonPushed(app, event)
            app.filterWithGuiOptions();
        end

        % Button pushed function: FilterResponseButton
        function FilterResponseButtonPushed(app, event)
            app.filterWithGuiOptions(true);
            
        end

        % Value changed function: DatasetnameEditField
        function DatasetnameEditFieldValueChanged(app, event)
            value = app.DatasetnameEditField.Value;
            app.setDatasetName(value);
            app.syncCurrentset();
            app.updateDatasetDropdown();
        end

        % Value changed function: DatasetDropDown
        function DatasetDropDownValueChanged(app, event)
            value = app.DatasetDropDown.Value;
            app.setCurrentDataset(value);
        end

        % Button pushed function: RemoveCurrentDatasetButton
        function RemoveCurrentDatasetButtonPushed(app, event)
            app.removeCurrentDataset();
        end

        % Button pushed function: MergeDatasetsButton
        function MergeDatasetsButtonPushed(app, event)
            idxs=inputdlg({'Enter index of base dataset',['Enter index' ...
                ' of the source dataset']},'Merge datsets');
            if(isempty(idxs) && length(idxs)~=2)
                return
            end
            app.mergeByDatasetIndex(str2double(idxs{1}),str2double(idxs{2}));
        end

        % Button pushed function: CreatetrialsButton
        function CreatetrialsButtonPushed(app, event)
            EPR_trials=app.convert2Trials();
            if(~isempty(EPR_trials))
               
                answer = questdlg(['What do you want to do with the' ...
                    ' extracted trials?'], ...
	                'Create trials', ...
	                'Open with Epoch Lab','Save','Cancel','Cancel');
                % Handle response
                switch answer
                    case 'Open with Epoch Lab'
                        EPR_trials.fn2='unsaved epochs';
                        app.toEpochLab(EPR_trials);
                    case 'Save'
                        app.saveData(EPR_trials);
                    case 'Cancel'
                        %
                end
                
            else
                warndlg('Failed to create trials','Create trials');
            end
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            app.importDlg();
        end

        % Button pushed function: TescsParametersButton
        function TescsParametersButtonPushed(app, event)
            app.openTescsParametersDlg();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.EPRecorderContinuousLabUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EPRecorderContinuousLabUIFigure = uifigure('Visible', 'off');
            app.EPRecorderContinuousLabUIFigure.Name = 'EP Recorder - Continuous Lab';
            app.EPRecorderContinuousLabUIFigure.CloseRequestFcn = createCallbackFcn(app, @EPRecorderContinuousLabUIFigureCloseRequest, true);
            app.EPRecorderContinuousLabUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.EPRecorderContinuousLabUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.InfoBox = uilabel(app.LeftPanel);
            app.InfoBox.Text = 'Open a file to get started';
            app.ActionDropDownLabel = uilabel(app.LeftPanel);
            app.ActionDropDownLabel.Text = 'Action';
            app.ActionDropDown = uidropdown(app.LeftPanel);
            app.ActionDropDown.Items = {'Select action', 'Quick recruitment curve'};
            app.ActionDropDown.ValueChangedFcn = createCallbackFcn(app, @ActionDropDownValueChanged, true);
            app.ActionDropDown.Value = 'Select action';
            app.YLimitLabel = uilabel(app.LeftPanel);
            app.YLimitLabel.Text = 'YLimit';
            app.YLimitEditField = uieditfield(app.LeftPanel, 'text');
            app.YLimitEditField.ValueChangedFcn = createCallbackFcn(app, @YLimitEditFieldValueChanged, true);
            app.YLimitEditField.Value = '[]';
            app.SaveButton = uibutton(app.LeftPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.OpenButton = uibutton(app.LeftPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.DisplaytimemsLabel = uilabel(app.LeftPanel);
            app.DisplaytimemsLabel.Text = 'Display time (ms)';
            app.DisplayTimeWidthEditField = uieditfield(app.LeftPanel, 'text');
            app.DisplayTimeWidthEditField.ValueChangedFcn = createCallbackFcn(app, @DisplayTimeWidthEditFieldValueChanged, true);
            app.DatasetDropDownLabel = uilabel(app.LeftPanel);
            app.DatasetDropDownLabel.Text = 'Dataset';
            app.DatasetDropDown = uidropdown(app.LeftPanel);
            app.DatasetDropDown.Items = {'None'};
            app.DatasetDropDown.ValueChangedFcn = createCallbackFcn(app, @DatasetDropDownValueChanged, true);
            app.DatasetDropDown.Value = 'None';
            app.DatasetnameEditFieldLabel = uilabel(app.LeftPanel);
            app.DatasetnameEditFieldLabel.Text = 'Dataset name';
            app.DatasetnameEditField = uieditfield(app.LeftPanel, 'text');
            app.DatasetnameEditField.ValueChangedFcn = createCallbackFcn(app, @DatasetnameEditFieldValueChanged, true);
            app.RemoveCurrentDatasetButton = uibutton(app.LeftPanel, 'push');
            app.RemoveCurrentDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveCurrentDatasetButtonPushed, true);
            app.RemoveCurrentDatasetButton.Tooltip = {'Remove the current dataset from memory without touching the file on the disk'};
            app.RemoveCurrentDatasetButton.Text = 'Clear';
            app.MergeDatasetsButton = uibutton(app.LeftPanel, 'push');
            app.MergeDatasetsButton.ButtonPushedFcn = createCallbackFcn(app, @MergeDatasetsButtonPushed, true);
            app.MergeDatasetsButton.Tooltip = {'Merge two datasets'};
            app.MergeDatasetsButton.Text = 'Merge datasets';
            app.ImportButton = uibutton(app.LeftPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.TescsParametersButton = uibutton(app.LeftPanel, 'push');
            app.TescsParametersButton.ButtonPushedFcn = createCallbackFcn(app, @TescsParametersButtonPushed, true);
            app.TescsParametersButton.Tooltip = {'Merge two datasets'};
            app.TescsParametersButton.Text = 'TESCS params';
            app.CenterPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.CurrentEpochInfoLabel = uilabel(app.RightPanel);
            app.CurrentEpochInfoLabel.Text = 'Open a file to get started';
            app.TabGroup = uitabgroup(app.RightPanel);
            app.PlayerTab = uitab(app.TabGroup);
            app.PlayerTab.Title = 'Player';
            app.PlayButton = uibutton(app.PlayerTab, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.PlayerTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PlaybackspeedKnobLabel = uilabel(app.PlayerTab);
            app.PlaybackspeedKnobLabel.Text = 'Playback speed';
            app.PlaybackspeedKnob = uiknob(app.PlayerTab, 'discrete');
            app.PlaybackspeedKnob.Items = {'Slow', 'Normal', 'Fast'};
            app.PlaybackspeedKnob.ItemsData = [5 1 0.1];
            app.PlaybackspeedKnob.ValueChangedFcn = createCallbackFcn(app, @PlaybackspeedKnobValueChanged, true);
            app.PlaybackspeedKnob.Value = 1;
            app.FilterTab = uitab(app.TabGroup);
            app.FilterTab.Title = 'Filter';
            app.CufoffLabel = uilabel(app.FilterTab);
            app.CufoffLabel.Text = 'Cufoff';
            app.FilterCufoffEditField = uieditfield(app.FilterTab, 'text');
            app.FilterCufoffEditField.Tooltip = {'Cut off frequency'};
            app.FilterCufoffEditField.Value = '[]';
            app.OrderLabel = uilabel(app.FilterTab);
            app.OrderLabel.Text = 'Order';
            app.FilterOrderEditField = uieditfield(app.FilterTab, 'text');
            app.FilterOrderEditField.Tooltip = {'FIlter order. Note that as the filter will be applied twice with filtfilt, consider entering half of the desired order.'};
            app.FilterOrderEditField.Value = '2';
            app.MethodLabel = uilabel(app.FilterTab);
            app.MethodLabel.Text = 'Method';
            app.FilterMethodDropDown = uidropdown(app.FilterTab);
            app.FilterMethodDropDown.Items = {'butter'};
            app.FilterMethodDropDown.Value = 'butter';
            app.FiltertypeDropDownLabel = uilabel(app.FilterTab);
            app.FiltertypeDropDownLabel.Text = 'Filter type';
            app.FilterTypeDropDown = uidropdown(app.FilterTab);
            app.FilterTypeDropDown.Items = {'low', 'high', 'bandpass', 'stop', 'notch'};
            app.FilterTypeDropDown.Value = 'low';
            app.ApplyFilterButton = uibutton(app.FilterTab, 'push');
            app.ApplyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterButtonPushed, true);
            app.ApplyFilterButton.Text = 'Apply';
            app.FilterResponseButton = uibutton(app.FilterTab, 'push');
            app.FilterResponseButton.ButtonPushedFcn = createCallbackFcn(app, @FilterResponseButtonPushed, true);
            app.FilterResponseButton.Text = 'Response';
            app.ChannelsLabel = uilabel(app.FilterTab);
            app.ChannelsLabel.Text = 'Channels';
            app.FilterChannelsEditField = uieditfield(app.FilterTab, 'text');
            app.FilterChannelsEditField.Tooltip = {'Indexes of channels  to filter. Channel 1 refers to the 2nd row in data, etc, since the first row is time.'};
            app.FilterChannelsEditField.Value = '[]';
            app.FilterZeroPhaseCheckBox = uicheckbox(app.FilterTab);
            app.FilterZeroPhaseCheckBox.Tooltip = {'Select to perform phase distortion-free filtering (filtfilt)'};
            app.FilterZeroPhaseCheckBox.Text = 'Zero phase';
            app.FilterZeroPhaseCheckBox.Value = true;
            app.TriggerTab = uitab(app.TabGroup);
            app.TriggerTab.Title = 'Trigger';
            app.CreatetrialsButton = uibutton(app.TriggerTab, 'push');
            app.CreatetrialsButton.ButtonPushedFcn = createCallbackFcn(app, @CreatetrialsButtonPushed, true);
            app.CreatetrialsButton.Text = 'Create trials';
            app.NextEpochButton = uibutton(app.RightPanel, 'push');
            app.NextEpochButton.ButtonPushedFcn = createCallbackFcn(app, @NextEpochButtonPushed, true);
            app.NextEpochButton.Text = 'Next>>';
            app.PreviousEpochButton = uibutton(app.RightPanel, 'push');
            app.PreviousEpochButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousEpochButtonPushed, true);
            app.PreviousEpochButton.Text = '<<Prev';
            % Show the figure after all components are created
            app.EPRecorderContinuousLabUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = eprecorder_continuous_lab(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EPRecorderContinuousLabUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EPRecorderContinuousLabUIFigure)
        end
    end
end