classdef eprecorder_epoch_lab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EPRecorderEpochLabUIFigure     matlab.ui.Figure
        SettingsMenu                   matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        ToworkspaceButton              matlab.ui.control.Button
        IndicatormodeDropDown          matlab.ui.control.DropDown
        IndicatormodeDropDownLabel     matlab.ui.control.Label
        TescsParametersButton          matlab.ui.control.Button
        DatasetnameEditField           matlab.ui.control.EditField
        DatasetnameEditFieldLabel      matlab.ui.control.Label
        DatasetDropDown                matlab.ui.control.DropDown
        DatasetDropDownLabel           matlab.ui.control.Label
        RemoveCurrentDatasetButton     matlab.ui.control.Button
        OpenButton                     matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        YLimitEditField                matlab.ui.control.EditField
        YLimitLabel                    matlab.ui.control.Label
        ActionDropDown                 matlab.ui.control.DropDown
        ActionDropDownLabel            matlab.ui.control.Label
        InfoBox                        matlab.ui.control.Label
        CenterPanel                    matlab.ui.container.Panel
        RightPanel                     matlab.ui.container.Panel
        PreviousEpochButton            matlab.ui.control.Button
        NextEpochButton                matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        PlayerTab                      matlab.ui.container.Tab
        PlaybackspeedKnob              matlab.ui.control.DiscreteKnob
        PlaybackspeedKnobLabel         matlab.ui.control.Label
        StopButton                     matlab.ui.control.Button
        PlayButton                     matlab.ui.control.Button
        QATab                          matlab.ui.container.Tab
        AutoRejectStdsEditField        matlab.ui.control.NumericEditField
        RespdetectthrLabel_2           matlab.ui.control.Label
        AutoRejectMethodDropDown       matlab.ui.control.DropDown
        NoisedetectionmethodLabel      matlab.ui.control.Label
        AutoRejectButton               matlab.ui.control.Button
        ResponseTab                    matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        DetectionTab                   matlab.ui.container.Tab
        ResponseFeatureDetectionMetricsButton  matlab.ui.control.Button
        DetectResponseFeaturesStopButton  matlab.ui.control.Button
        MEPClassifierDropDown          matlab.ui.control.DropDown
        ClassifierDropDownLabel        matlab.ui.control.Label
        AutoDetectionCurrentEpochOnlyCheckBox  matlab.ui.control.CheckBox
        AutoChangeTimeDetectionMethodDropDown  matlab.ui.control.DropDown
        ChangetimedetectionLabel       matlab.ui.control.Label
        AutoPresenceDetectionMethodDropDown  matlab.ui.control.DropDown
        ChangetimedetectionLabel_2     matlab.ui.control.Label
        ResponseStdsEditField          matlab.ui.control.NumericEditField
        RespdetectthrLabel             matlab.ui.control.Label
        DetectResponseFeaturesButton   matlab.ui.control.Button
        ResponseNormalisationTab       matlab.ui.container.Tab
        StimcodetointenEditField       matlab.ui.control.EditField
        StimcodetointenLabel           matlab.ui.control.Label
        Datasetmustbepreprocessedfortypemaxresppeak2peakLabel  matlab.ui.control.Label
        ResponseNormalisationMaxp2pTextArea  matlab.ui.control.TextArea
        Maxrespp2pLabel                matlab.ui.control.Label
        ResponseNormalisationMVCrmsTextArea  matlab.ui.control.TextArea
        MVCrmsTextAreaLabel            matlab.ui.control.Label
        UpdatewithsourcedatasetPanel   matlab.ui.container.Panel
        ResponseNormalisationTypeDropDown  matlab.ui.control.DropDown
        TypeLabel                      matlab.ui.control.Label
        ResponseNormalisationSourcedatasetButton  matlab.ui.control.Button
        PlotResponseTab                matlab.ui.container.Tab
        OpenAverageDlgButton           matlab.ui.control.Button
        OpenRecruitmentCurveDlgButton  matlab.ui.control.Button
        LabelTab                       matlab.ui.container.Tab
        CopyPanel                      matlab.ui.container.Panel
        LabelTimesFromDatasetButton    matlab.ui.control.Button
        GuessLabelTimesButton          matlab.ui.control.Button
        LabelTimesFromeResponseButton  matlab.ui.control.Button
        ExportdataPanel                matlab.ui.container.Panel
        LabelExportButton              matlab.ui.control.Button
        LabelExportMinSegmentSampleLenEditField  matlab.ui.control.NumericEditField
        MinsegmentsamplesLabel         matlab.ui.control.Label
        LabelExportExcludeLabelsEditField  matlab.ui.control.EditField
        ExcllabelsEditFieldLabel       matlab.ui.control.Label
        ToggleRejectEpochButton        matlab.ui.control.Button
        TrialDropDown                  matlab.ui.control.DropDown
        TrialDropDownLabel             matlab.ui.control.Label
        CurrentEpochInfoLabel          matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    % Declaration for tags for UI objects.
    properties(Access=private)
        %  Displays info for axis data.
        uiTags_axisInfo='axis-footer-info-label';

        % Axis EP response toggling button
        uiTags_axisResponseBtn='axis-footer-response-btn'

        % Axis quick delete toggling button
        uiTags_axisRejectBtn='axis-footer-delete-btn'

        % The button to edit settings for the current channel and the epoch
        % response 
        uiTags_axisResponseSettingsBtn='axis-response-settings-btn';

        % Sub Figure for settings
        uiTags_SettingsFigure='eprecorder-settings-fig';

        % Sub Figure for response settings
        uiTags_ResponseSettingsFigure='eprecorder-epoch-response-settings-fig';

        % Sub Figure for data label
        uiTags_LabelFigure='eprecorder-epoch-label-fig';

        % Btn to open data label figure from axes
        uiTags_axisLabelFigureBtn='eprecorder-epoch-label-btn';

        % Sub Figure for recruitment curve
        uiTags_RecruitmentCurveDlgFigure='eprecorder-epoch-response-recruitment-curve-fig';

        % Sub Figure for average
        uiTags_AverageDlgFigure='eprecorder-epoch-response-average-fig';

        %
        TescsParametersDlgApp=[];% Reference to tescs param dlg app.
    end

    % Settings
    properties(Access=private)
        % [An example setting to show how it is done]
        % When 'force' clicking on response toggle button on an axis changes
        % the manual response presence to either 0 or 1. When 'normal' it is
        % set to either 1 or NaN(i.e auto).
        % string:{'normal','force'}
        setting__example_toggle_mode = 'normal';
    end


    % GUI data
    properties(Access=private)
        MEPClassifiers={}; % List of available MEP classifiers
    end

    properties (Access = private)
        name='EP Recorder - Epoch Lab';% The base name of the app.
        allEPR={};% Array of all loaded EPR datasets
        EPR ={};% EPRecorder data structure
        currentSet=0;% The index of the current dataset
        channelAxes={};%Axes for each channel
        numEpochs=0; % Number of epochs
        numChans=0;% Number channels;
        currentEpochNumber=[];% The current epoch number

        yLimit=[];% The ylimit of all axis that are channel data

        indicatorMode='normal';%{'normal','label'=>for data label}

        % Temp vars for storing session states
        temp=struct( ...
            'label_last_export_filename',[] ...
            );
    end
    
    methods (Access = private)
        function loadMEPClassifiers(app)
            path=fileparts(mfilename('fullpath'));
            path=fullfile(path,'classification');
            addpath(path);
            
            app.MEPClassifiers=mepclassifier.ClassifierManager().all();
        end
        function setDatasetName(app,name)
            % Set the name of the current dataset.
            % [INPUT]
            % name string: The new name.
            app.EPR.name=name;
        end
        function name=getDatasetName(app)
            % Get the name of the current dataset.
            % [OUTPUT]
            % name string: The name.
            name='newset';
            if(isfield(app.EPR,'name'))
                name=app.EPR.name;
            end
        end
        function name=channelNumber2Name(app,chan_num)
            % Convert a given channel number to name.
            % [INPUT]
            % chan_num: The channel number.
            % [OUTPUT]
            % name string: channel name
            name=app.EPR.channelNames{chan_num};
        end
        function prepareData(app)
            % Prepare the data for processing
            
            app.numEpochs=1;
            app.currentEpochNumber=1;
            app.numChans=length(app.EPR.channelNames);
            if(~app.isEpoched())
                answer=questdlg('Would you like to epoch the data?');
                if(strcmp(answer,'Yes'))
            
                    epoch_time_win=[-20,100];
                    epoch_time_win=inputdlg(['Create epochs: Choose a ' ...
                        'time window (ms)'],'Epoching',1, ...
                        {mat2str(epoch_time_win)});

                    epoch_time_win=str2num(epoch_time_win{1});
                    app.EPR=eprecorder_epoch(app.EPR,epoch_time_win);                   
                    
                end
            end

            if(app.isEpoched())
                app.numEpochs=length(app.EPR.epochs.codes);
            end

            app.updateTrialDropDown();

            app.addFields()
        end

        function addFields(app)
            % Add any additional fields to the EPR structure.
        end

        function updateTrialDropDown(app)
            % update the dropdown list of trials.
            n_epochs=app.numEpochs;
            trials=num2cell((1:n_epochs)');
            to_cell_strs=@(item)mat2str(item{1});
            trials=app.array_map(trials,to_cell_strs);
            app.TrialDropDown.Items=trials;
        end
        function showInfo(app)
            % Show main info for the EPR data structure

            filename=strrep(app.EPR.fn2,'\','/');
            filename=strsplit(filename,'/');
            filename=filename{end};


            info={
                ['Timepoint: ' app.EPR.timepoint]
                ['Tag: ' app.EPR.tag]
                ['Side|Cond|Param|Target: ',sprintf('%s|%s|%s|%s',app.EPR.side,app.EPR.condition,app.EPR.param,app.EPR.target)]
                ['Model: ' app.EPR.model]
                ['Model file: ' app.EPR.modelFile]
                ['Recording data folder: ' app.EPR.dataFolder]
                ['File: ' filename]
                ['Sample rate: ' mat2str(app.EPR.Fs)]
                ['Subject ID: ' mat2str(app.EPR.subjectId)]
                ['Section number: ' mat2str(app.EPR.sectionN)]
                ['Subsection number: ' mat2str(app.EPR.subsection)]
                ['Date: ' datestr(app.EPR.date)]
                ['EPR data version: ' app.EPR.version],
            };
            app.InfoBox.Text=info;

            app.DatasetnameEditField.Value=app.getDatasetName();
        end

        function visualise(app)
            % Visualise data
            app.buildAxes();
        end
        function clearAxes(app)
            % Clear visualisation axes.

            % We delete the Children and aspect garbage collector to remove
            % ani visual object that may be children of the Children
            delete(app.CenterPanel.Children);
        end
        
        function buildAxes(app)
            % Build the axes for data visualisation
            app.clearAxes();

            nchans=length(app.EPR.channelNames);
            ngridrows=ceil(nchans/2);
            ngridcols=2;
            if nchans==1
                ngridcols=1;
            end
            centerPanelGrid=uigridlayout(app.CenterPanel,[ngridrows,ngridcols]);
            centerPanelGrid.Padding=[3,3,3,3];
            centerPanelGrid.ColumnSpacing=2;
            centerPanelGrid.RowSpacing=2;

            for chan=1:nchans
                axisPanel=uipanel(centerPanelGrid);
                axisPanel.BorderType="none";
                axisPanelGrid=uigridlayout(axisPanel,[2,1]);
                axisPanelGrid.RowHeight = {150,44};
                axisPanelGrid.ColumnWidth = {'1x'};
                axisPanelGrid.Padding=[0,0,0,0];
                axisPanelGrid.ColumnSpacing=0;
                axisPanelGrid.RowSpacing=0;

                ax=uiaxes(axisPanelGrid);
                udata.footerPanel=app.buildAxisFooter(axisPanelGrid,chan);
                ax.UserData=udata;
                
                ax.NextPlot="replacechildren";
                title(ax,[mat2str(chan),':',app.channelNumber2Name(chan)])
                ylabel(ax,app.EPR.channelUnits{chan})
                xlabel(ax,'ms')
                app.channelAxes{chan}=ax;
            end
            
        end

        function panel=buildAxisFooter(app,axisPanelGrid,chan_num)
            % Build the footer of an axis for an epoch channel.
            % [INPUT]
            % axisPanelGrid Grid: The grid contaning the axis for the given
            %   channel.
            % chan_num: The channel number for the axis
            % [OUTPUT]
            % panel uipanel: The uipanel the contaning the footer
            % 
            panel=uipanel(axisPanelGrid);
            panel.BorderType="none";
            panelGrid=uigridlayout(panel,[2,3]);
            panelGrid.Padding=[0,0,0,0];
            panelGrid.ColumnSpacing=0;
            panelGrid.RowSpacing=0;
            
            infoLabel=uilabel(panelGrid,"Text",'','Tag',app.uiTags_axisInfo);
            infoLabel.Layout.Column=[1,4];
            infoLabel.HorizontalAlignment = 'center';

            responseBtn=uibutton(panelGrid,"push","Text","Toggle response","UserData",chan_num, ...
                "ButtonPushedFcn",@app.ToggleResponsePresenceButtonPushed,...
                "FontSize",9,...
                "Tooltip","Toggles the channel's manual response presence for the " + ...
                "current epoch b/w present, absence & unset", ...
                'Tag',app.uiTags_axisResponseBtn);
            responseBtn.HorizontalAlignment = 'center';

            rejectBtn=uibutton(panelGrid,"push","Text","Toggle reject","UserData",chan_num, ...
                "ButtonPushedFcn",@app.ToggleRejectButtonPushed,...
                "FontSize",9,...
                "Tooltip","Toggles manual reject b/w unrejected, rejected & unset", ...
                'Tag',app.uiTags_axisRejectBtn);
            rejectBtn.HorizontalAlignment = 'center';

            editChannelBtn=uibutton(panelGrid,"push","Text","Edit","UserData",chan_num, ...
                "ButtonPushedFcn",@app.ResponseSettingsButtonPushed,...
                "FontSize",9,...
                "Tooltip","Edit the channel and current epoch Response settings",...
                'Tag',app.uiTags_axisResponseSettingsBtn);
            editChannelBtn.HorizontalAlignment = 'center';

            labellingBtn=uibutton(panelGrid,"push","Text","Label","UserData",chan_num, ...
                "ButtonPushedFcn",@app.LabelButtonPushed,...
                "FontSize",9,...
                "Tooltip","Label the current epoch data",...
                'Tag',app.uiTags_axisLabelFigureBtn);
            labellingBtn.HorizontalAlignment = 'center';
            
            
        end
        function plotEpoch(app,ax,chan_num,epoch_num,indicators)
            % Plot the data of an epoch on a given axis.
            % [INPUT]
            % ax: Matlab uiaxis.
            % chan_num int: Channel number.
            % epoch_num int: Epoch number.
            % indicators cell<string>:The list of indicator to show. The
            %   default is {'all'}. See showIndicators() for posible
            %   values.

            if nargin<5
                indicators={'all'};
            end

            data=eprecorder_get_epoch_data(app.EPR,chan_num,epoch_num);
            lineObj=plot(ax,app.EPR.times,data,"DisplayName",'Channel data','Tag','channel-data');
            if(app.hasResponse(chan_num,epoch_num))
                % Uncomment to change the line color
                %lineObj.Color=[0,204,0]/255;% ie. Colored when response is present for the first stimulus.
            end
            if(app.isEpochEffectivelyRejected(chan_num,epoch_num))
                lineObj.Color=[204,204,204]/255;
            end
            
            app.showIndicators(ax,chan_num,epoch_num,indicators);

        end
        function gotoEpoch(app,epoch_num)
            % Plot the data for epoch specified by the epoch number.
            % [INPUT]
            % epoch_num integer: The epoch number of the trial.
            % 
            app.currentEpochNumber=epoch_num;

            for n=1:(length(app.channelAxes))
                ax=app.channelAxes{n};
                app.plotEpoch(ax,n,epoch_num);
                 app.updateCurrentEpochAxisInfo(n);
            end
            app.updateCurrentEpochInfo();

            
            % Sync with trial dropdown 
            value = app.TrialDropDown.Value;
            if(str2double(value)~=app.currentEpochNumber)
                app.TrialDropDown.Value=app.TrialDropDown.Items(app.currentEpochNumber);
            end
        end
        function gotoNextEpoch(app,loop)
            % Plot the data for the epoch next to the curren one.
            % loop boolean: When true, will start from the first epoch when
            % the end is reached.
            if nargin<2
                loop=true;
            end

            current_num=app.currentEpochNumber;
            if(current_num==app.numEpochs)
                if(loop)
                    current_num=0;
                else
                    return
                end
            end
            current_num=current_num+1;
            app.gotoEpoch(current_num)
        end
        function gotoPreviousEpoch(app,loop)
            % Plot the data for the epoch previous to the curren one.
            % loop boolean: When true, will go to the last epoch when
            % the first is reached.
            if nargin<2
                loop=true;
            end

            current_num=app.currentEpochNumber;
            if(current_num==1)
                if(loop)
                    % Overshoot by one, it will be corrected below.
                    current_num=app.numEpochs+1;
                else
                    return
                end
            end
            current_num=current_num-1;
            app.gotoEpoch(current_num)
        end
        
        function redrawEpoch(app,epoch_num)
            % Plot the given or replot current epoch.
            % epoch_num int: The epoch number to plot. The default is the
            % current epoch.  
            if nargin<2
                epoch_num=app.currentEpochNumber;
            end
            app.gotoEpoch(epoch_num);
        end

        
        function showIndicators(app,ax,chan_num,epoch_num,indicators)
            % Show the trigger on the axis for then given channel.
            % [INPUT]
            % ax: Matlab uiaxis. The default is the axis for the given
            %   channel. Set to empty [] to use the default.
            % chan_num integer: The channel number 
            % epoch_num integer: Epoch number
            % indicators cell<string>:The list of indicator to show. The
            %   default is {'all'}
            if nargin<5 || any(strcmp(indicators,'all'))

                indicators={'labels'};
                if ~strcmp(app.indicatorMode,'label')
                    indicators={
                        'trigger'
                        'response-time-window'
                        'response-auto-onset-time'
                        'response-auto-stop-time'
                        'response-manual-onset-time'
                        'response-manual-stop-time'
                        'effective-response-times'
                        'response-features'
                        };
                end
            end
            
            % NOTE Indicators that depends on axis data such as ylim, may
            % sometimes(except for first epoch show where this is alway the
            % case) not fill the y-axes nicely. This is likely b/c such
            % indicators may have been plotted before the axes data are
            % made available which could happen when plotting in a loop
            % without calling 'drawnow'. It may be slow, but we can attempt 
            % to fix this issue by calling 'drawnow' here first before
            % executing the indicators.
            if any(strcmp(indicators,'trigger'))
                app.showTrigger(ax,chan_num,epoch_num);
            end
            if any(strcmp(indicators,'response-time-window'))
                app.showResponseTimeWindowndicator(ax,chan_num);
            end
            if any(strcmp(indicators,'response-auto-onset-time'))
                app.showResponseAutoOnsetTimeIndicator(ax,chan_num,epoch_num);
            end
            if any(strcmp(indicators,'response-auto-stop-time'))
                app.showResponseAutoStopTimeIndicator(ax,chan_num,epoch_num);
            end
            if any(strcmp(indicators,'response-manual-onset-time'))
                app.showResponseManualOnsetTimeIndicator(ax,chan_num,epoch_num);
            end
            if any(strcmp(indicators,'response-manual-stop-time'))
                app.showResponseManualStopTimeIndicator(ax,chan_num,epoch_num);
            end
            if any(strcmp(indicators,'effective-response-times'))
                app.showEffectiveResponseTimesIndicator(ax,chan_num,epoch_num);
            end
            if any(strcmp(indicators,'response-features'))
                app.showResponseFeaturesIndicator(ax,chan_num,epoch_num);
            end
            if any(strcmp(indicators,'labels'))
                app.showLabelsIndicator(ax,chan_num,epoch_num);
            end
        end
        function showTrigger(app,ax,chan_num,epoch_num)
            % Show the trigger on the axis for then given channel.
            % [INPUT]
            % ax: Matlab uiaxis. The default is the axis for the given
            %   channel. Set to empty [] to use the default.
            % chan_num integer: The channel number where the trigger is to
            %   be plotted.
            % epoch_num integer: Epoch number
            if isempty(ax)
                ax=app.channelAxes{chan_num};
            end

            lim=ylim(ax);
            y=lim/4;

            primary_trigger=0;% Primary trigger is always only at zero as this is how data was triggered.
            actual_secondary_triggers=[];
            if ~isempty(app.EPR.epochs.secondary_trigs)
                actual_secondary_triggers=app.EPR.epochs.secondary_trigs(epoch_num,:)-app.EPR.epochs.trigs(epoch_num);
            end
            trigger_times=eprecorder_sample2time(app.EPR,[primary_trigger,actual_secondary_triggers])*1000;
            for n=1:length(trigger_times)
                x=[trigger_times(n),trigger_times(n)];
                app.addPlot(ax,x,y,'-r',"DisplayName",['Trigger ',mat2str(n)]);
            end

        end
        function showResponseTimeWindowndicator(app,ax,chan_num)
            % Show the indicator for defined response time window.
            % [INPUT]
            % ax: Matlab uiaxis. The default is the axis for the given
            %   channel. Set to empty [] to use the default.
            % chan_num integer: The channel number 
            
            if isempty(ax)
                ax=app.channelAxes{chan_num};
            end
            lim=ylim(ax);
            y=lim(2)/4;
            y=[y,y];

            stimulus_nums=eprecorder_stimulus_numbers(app.EPR);
            for stimulus_num=stimulus_nums
                x=eprecorder_response.getTimeWin(app.EPR,chan_num,stimulus_num);

                app.addPlot(ax,x,y,'-m', ...
                    "LineWidth",1,...
                    "DisplayName",['Response time window ' mat2str(stimulus_num)]);
            end
        end

        function showResponseAutoOnsetTimeIndicator(app,ax,chan_num,epoch_num)
            % Show the indicator for the automatically detected response
            % onset time. 
            % [INPUT]
            % ax: Matlab uiaxis. The default is the axis for the given
            %   channel. Set to empty [] to use the default.
            % chan_num integer: The channel number 
            % epoch_num integer: Epoch number
            if isempty(ax)
                ax=app.channelAxes{chan_num};
            end
            lim=ylim(ax);
            y=lim;

            stimulus_nums=eprecorder_stimulus_numbers(app.EPR);
            for stimulus_num=stimulus_nums
                x=eprecorder_response.getAutoOnsetTime(app.EPR,chan_num,epoch_num,stimulus_num);
                x=[x,x];
    
                app.addPlot(ax,x,y,'-k', ...
                    "LineWidth",1,...
                    "DisplayName",['Response auto onset time ' mat2str(stimulus_num)]);
            end
        end

        function showResponseAutoStopTimeIndicator(app,ax,chan_num,epoch_num)
            % Show the indicator for the automatically detected response
            % stop time. 
            % [INPUT]
            % ax: Matlab uiaxis. The default is the axis for the given
            %   channel. Set to empty [] to use the default.
            % chan_num integer: The channel number 
            % epoch_num integer: Epoch number
            if isempty(ax)
                ax=app.channelAxes{chan_num};
            end
            lim=ylim(ax);
            y=lim;

            stimulus_nums=eprecorder_stimulus_numbers(app.EPR);
            for stimulus_num=stimulus_nums
                x=eprecorder_response.getAutoStopTime(app.EPR,chan_num,epoch_num,stimulus_num);
                x=[x,x];
    
                app.addPlot(ax,x,y,'--k', ...
                    "LineWidth",1,...
                    "DisplayName",['Response auto stop time ' mat2str(stimulus_num)]);
            end
        end

        function showResponseManualOnsetTimeIndicator(app,ax,chan_num,epoch_num)
            % Show the indicator for the manually entered response
            % onset time. 
            % [INPUT]
            % ax: Matlab uiaxis. The default is the axis for the given
            %   channel. Set to empty [] to use the default.
            % chan_num integer: The channel number 
            % epoch_num integer: Epoch number
            if isempty(ax)
                ax=app.channelAxes{chan_num};
            end
            lim=ylim(ax);
            y=lim;

            stimulus_nums=eprecorder_stimulus_numbers(app.EPR);
            for stimulus_num=stimulus_nums
                x=eprecorder_response.getManualOnsetTime(app.EPR,chan_num,epoch_num,stimulus_num);
                x=[x,x];
    
                app.addPlot(ax,x,y,':y', ...
                    "LineWidth",1,...
                    "DisplayName",['Response manual onset time ' mat2str(stimulus_num)]);
            end
        end

        function showResponseManualStopTimeIndicator(app,ax,chan_num,epoch_num)
            % Show the indicator for the manually entered response
            % stop time . 
            % [INPUT]
            % ax: Matlab uiaxis. The default is the axis for the given
            %   channel. Set to empty [] to use the default.
            % chan_num integer: The channel number 
            % epoch_num integer: Epoch number
            if isempty(ax)
                ax=app.channelAxes{chan_num};
            end
            lim=ylim(ax);
            y=lim;

            stimulus_nums=eprecorder_stimulus_numbers(app.EPR);
            for stimulus_num=stimulus_nums
                x=eprecorder_response.getManualStopTime(app.EPR,chan_num,epoch_num,stimulus_num);
                x=[x,x];
    
                app.addPlot(ax,x,y,':c', ...
                    "LineWidth",1,...
                    "DisplayName",['Response manual stop time ' mat2str(stimulus_num)]);
            end
        end
        function showEffectiveResponseTimesIndicator(app,ax,chan_num,epoch_num)
            % Show the indicator(a shaded area) for the effective response
            % time.  
            % [INPUT]
            % ax: Matlab uiaxis. The default is the axis for the given
            %   channel. Set to empty [] to use the default.
            % chan_num integer: The channel number 
            % epoch_num integer: Epoch number

            stimulus_nums=eprecorder_stimulus_numbers(app.EPR);
            for stimulus_num=stimulus_nums

                effective_rt=[
                    eprecorder_response.getEffectiveOnsetTime(app.EPR,chan_num,epoch_num,stimulus_num),...
                    eprecorder_response.getEffectiveStopTime(app.EPR,chan_num,epoch_num,stimulus_num)...
                ];
                lim=ylim(ax);
    
                x=effective_rt(1);
                w=effective_rt(2) - effective_rt(1);
                y=lim(1);
                h=lim(2)-lim(1);

                face_color=[0.466,0.674,0.188,0.1];
                if (eprecorder_response.has(app.EPR,chan_num,epoch_num,stimulus_num))
                    face_color=[0,1,0,0.3];
                end
    
                rectangle(ax,"position",[x,y,w,h],"FaceColor", ...
                    face_color,"LineStyle","none");
                if stimulus_num==1
                    text(ax,x+w/8,y+h/8,{'*Effective', 'response region'},"FontSize",6);
                end
            end
            
        end
        function showResponseFeaturesIndicator(app,ax,chan_num,epoch_num)
            % Show the indicator for features of the response.
            % [INPUT]
            % ax: Matlab uiaxis. The default is the axis for the given
            %   channel. Set to empty [] to use the default.
            % chan_num integer: The channel number 
            % epoch_num integer: Epoch number
            stimulus_nums=eprecorder_stimulus_numbers(app.EPR);
            for stimulus_num=stimulus_nums
                p2p=eprecorder_response.getPeak2peak( ...
                    app.EPR,chan_num,epoch_num,[],false,false,stimulus_num);
                ar=eprecorder_response.getArea( ...
                    app.EPR,chan_num,epoch_num,[],false,false,stimulus_num);
                t_width=eprecorder_response.getEffectiveTimeWidths( ...
                    app.EPR,chan_num,epoch_num,[],false,false,stimulus_num);
    
                effective_rt=[
                    eprecorder_response.getEffectiveOnsetTime(app.EPR,chan_num,epoch_num,stimulus_num),...
                    eprecorder_response.getEffectiveStopTime(app.EPR,chan_num,epoch_num,stimulus_num)...
                ];
                lim=ylim(ax);
    
                x=effective_rt(1);
                w=effective_rt(2) - effective_rt(1);
                y=lim(1);
                h=lim(2)-lim(1);

                text_color=[0,0,0];
                font_weight='normal';
                if (eprecorder_response.has(app.EPR,chan_num,epoch_num,stimulus_num))
                    font_weight='bold';
                end
                text(ax,x+w/8,y+h/1.2, ...
                    {sprintf('Peak2peak %g: %g',stimulus_num,p2p), ...
                    sprintf('Area %g: %g',stimulus_num,ar), ...
                    sprintf('Width %g: %g',stimulus_num,t_width)}, ...
                    "FontSize",6,"Color",text_color,"FontWeight",font_weight);
            end
        end
        function showLabelsIndicator(app,ax,chan_num,epoch_num)
            % Show the indicator for data labels.  
            % [INPUT]
            % ax: Matlab uiaxis. The default is the axis for the given
            %   channel. Set to empty [] to use the default.
            % chan_num integer: The channel number 
            % epoch_num integer: Epoch number
             
            data=eprecorder_get_epoch_data(app.EPR,chan_num,epoch_num);
            data_std=std(data);

            y=[min(data)-data_std,max(data)+data_std];
            [labels,colors]=eprecorder_label.getFields();

            stimulus_nums=eprecorder_stimulus_numbers(app.EPR);
            for stimulus_num=stimulus_nums

                colorIdx=0;
                for label_n=1:length(labels)

                    colorIdx=colorIdx+1;
                    if colorIdx>length(colors),colorIdx=1;end

                    x1=eprecorder_label.getTime(labels{label_n}{1},app.EPR,chan_num,epoch_num,stimulus_num);
                    x2=eprecorder_label.getTime(labels{label_n}{2},app.EPR,chan_num,epoch_num,stimulus_num);
            
                    rectangle(ax,'Position', [x1 y(1) x2-x1 y(2)-y(1)],'LineWidth',2,'EdgeColor',colors{colorIdx},"FaceColor",colors{colorIdx});
                end
             
            end
            
        end
        function updateCurrentEpochInfo(app)
            % Update the current display epoch's information.
            epoch_num=app.currentEpochNumber;

            rejected='No';
            if(app.isEpochEffectivelyRejected([],epoch_num))
                rejected='Yes';
            end
            
            info={
                sprintf('Epoch: %d/%d',epoch_num,app.numEpochs)
                sprintf('Event code: %g',app.eventCode(epoch_num))
                sprintf('Trigger time: %d samples',app.eventTrig(epoch_num))
                sprintf('All channel rejected: %s',rejected)
                ['Notes: ' app.note(epoch_num)],
            };
            app.CurrentEpochInfoLabel.Text=info;
        end

        function updateCurrentEpochAxisInfo(app,chan_num)
            % Update the current display epoch's information for a given
            % channel. 
            % [INPUT]
            % chan_num integer: The channel numer for the axis to be updated.
            epoch_num=app.currentEpochNumber;

            ax=app.channelAxes{chan_num};
            footerPanel=ax.UserData.footerPanel;
            h = findobj(footerPanel,'Tag',app.uiTags_axisInfo);
            infoLabel=h(1);

            response='?';
            if(app.hasResponse(chan_num,epoch_num))
                response='Present';% Response is present if it is present for the first stimulus.
            end
            
            info={
                sprintf('Response: %s',response)
            };
            infoLabel.Text=info;
        end

    end

    % Public methods
    methods(Access=public)
        function applyYLimit(app,ylimit)
            % Apply the given or current ylimit
            % [INPUT]
            % limit array<float>: 2-ele array. The defaylt is app.yLimit 
            if nargin<2
                ylimit=app.yLimit;
            end

            if(isempty(ylimit))
                return
            end

            for n=1:app.numChans
                app.setYLimit(ylimit,n);
            end
        end
        function setYLimit(app,ylimit,chan_num)
            % set the ylimit of the specified channel
            % [INPUT]
            % ylimits array: A 2-D array to set ylim.
            % chan_num integer: The channel number to update
            ax=app.channelAxes{chan_num};
            ylim(ax,ylimit);
        end
    end

    % Epoch/Frame helper methods
    methods(Access=private)

        function is_epoched=isEpoched(app)
            % Check the data has been epoched.
            % [OUTPUT]
            % is_epoched boolean: True if the data is epoched.

            is_epoched=false;
            if(isfield(app.EPR,'epochs') && ~isempty(app.EPR.epochs))
                is_epoched=true;
            end
        end

        function data=trial(app,epoch_num)
            % Return the data for epoch specified by the epoch number.
            % [INPUT]
            % epoch_num integer: The epoch number of the trial.
            % [OUTPUT]
            % data array: The trial data. 3-d (chan x time).
            data=eprecorder_get_epoch_data(app.EPR,[],epoch_num);
        end

        function code=eventCode(app,epoch_num)
            % Return the event code for epoch specified by the epoch
            % number. 
            % [INPUT]
            % epoch_num integer: The epoch number of the trial.
            % [OUTPUT]
            % code string: The event code
            code=app.EPR.epochs.codes(epoch_num);
        end

        function trig=eventTrig(app,epoch_num)
            % Return the event time for epoch specified by the epoch
            % number. 
            % [INPUT]
            % epoch_num integer: The epoch number of the trial.
            % [OUTPUT]
            % trig double: The event start time
            trig=app.EPR.epochs.trigs(epoch_num);
        end

        function s=note(app,epoch_num)
            % Return the note entered for epoch specified by the epoch
            % number. 
            % [INPUT]
            % epoch_num integer: The epoch number of the trial.
            % [OUTPUT]
            % data string: The note entered for the epoch
            s='';
            note_e=app.EPR.epochs.notes{epoch_num};
            if(~isempty(note_e))
                s=note_e{2};
            end
        end


        function has_response=hasResponse(app,chan_num,epoch_num,stimulus_num)
            % Check if the epoch given by epoch number has EP response as
            % detected maulally or automatically.
            % [INPUT]
            % chan_num integer: The channel number to check in the trial.
            % epoch_num integer: The epoch number of the trial.     
            % stimulus_num int: Stimulus number. Default is the primary
            %   stimulus number.
            % 
            % [OUTPUT]
            % has_reponse boolean: True if the epoch has EP response
            
            if nargin < 4
                stimulus_num=1;
            end

            has_response=eprecorder_response.has(app.EPR,chan_num,epoch_num,stimulus_num);
            has_response=~~has_response;

        end

        function setResponseManualPresence(app,chan_num,epoch_num,response_presence,stimulus_num)
            % Set the maulally detected EP presence status of the given
            % epoch. 
            % [INPUT]
            % chan_num: The Channel whos EP presence is to be set.
            % epoch_num integer: The epoch number of the trial.
            % response_presence boolean: True to set that EP is present in the
            %   given epoch.
            % stimulus_num int: Stimulus number. Default is the primary
            %   stimulus number.
            % 
            if nargin < 5
                stimulus_num=1;
            end
            app.EPR=eprecorder_response.setManualPresence(app.EPR,chan_num,epoch_num,response_presence,stimulus_num);

        end
    end

    % Properties for player
    properties(Access=private)
        playerTimer=[];% Matlab timer object for the player
    end

    % Methods for epoch player
    methods(Access=private)
        function startPlayer(app,speed)
            % Play epoch starting from the current trial to the end
            % [INPUT]
            % speed int|double: The playback speed in seconds
            if nargin <2
                speed= app.PlaybackspeedKnob.Value;
            end
            
            t=timer('Period', speed, ...
                'StartDelay',speed,...
                'ExecutionMode','fixedRate', ...
                'TasksToExecute',10000);% We set an arbitrary high num here.
            t.TimerFcn=@playerTimerCallback;

            
            app.playerTimer=t;
            
            app.PlayButton.Enable="off";
            app.StopButton.Enable="on";
            start(app.playerTimer);

            function playerTimerCallback(timerObj,event)
                % Timer callback function 
                if(app.currentEpochNumber==app.numEpochs)
                    app.stopPlayer();
                end
                app.gotoNextEpoch(false);
            end
        end

        function stopPlayer(app)
            % Stop play back of trials
            stop(app.playerTimer);
            delete(app.playerTimer);
            app.PlayButton.Enable="on";
            app.StopButton.Enable="off";
        end

        function is_playing=isPlaying(app)
            % Check if the player is playing
            % [OUTPUT]
            % is_playing boolean: True if the playing is playing.
            is_playing=false;
            if(~isempty(app.playerTimer))
                if(isvalid(app.playerTimer) && strcmp(app.playerTimer.Running,'on'))
                    is_playing=true;
                    return;
                end
            end
        end
            
    end

    % Quality assessment
    methods(Access=public)
        function result=isEpochEffectivelyRejected(app,chan_num,epoch_num)
            % Check if the epoch given by epoch number is effectively
            % rejected.
            % [INPUT]
            % chan_num integer: The channel number. Set to empty bracket
            %   for all channels.
            % epoch_num integer: The epoch number of the trial.
            % [OUTPUT]
            % result array<boolean>: An array of size [chan_num x epoch_num] where
            %   an entry of true indicates that the corresponding input channel and
            %   input epoch is rejected.
            result=eprecorder_epoch_qa.isEffectivelyRejected(app.EPR,chan_num,epoch_num);
            
        end

        function manualReject(app,chan_nums,epoch_nums,rejects)
            % Manually mark the given epoch number as rejected or not.
            % [INPUT]
            % chan_num int|array<int>: The channel number/s. Set to empty
            %   bracket for all channels.
            % epoch_num int|array<int>: The epoch number/s of the trial.
            %   Set to empty bracket for all epochs.
            % reject bool|int|array<bool|int>: True to reject. Scalar or
            %   size=[chan_nums,epoch_nums]. 
            
            app.EPR=eprecorder_epoch_qa.setManualReject(app.EPR,chan_nums,epoch_nums,rejects);
            

            % Add history
            history=sprintf([ ...
                    'EPR=eprecorder_epoch_qa.setManualReject(EPR,%g,%g,%g);\n ' ...
                ], ...
                chan_nums,epoch_nums,rejects ...
            );
            eprecorder_add_history(app.EPR,history,'Noise rjection');
        end

        function autoReject(app,noise_detection_method,noise_detection_thresh)
            % Reject noisy epochs in the dataset.
            % [INPUT]
            % noise_detection_method string: Automatic noise detection
            %   method. 
            % noise_detection_thresh double: The number of feature standard
            %   daviations or errors above baseline at which noise is
            %   detected. 
            qa=eprecorder_epoch_qa(noise_detection_method,noise_detection_thresh);
            app.EPR=qa.autoReject(app.EPR);
            app.redrawEpoch();

            % Add history
            history=sprintf([ ...
                    'qa=eprecorder_epoch_qa(%s,%s);\n ' ...
                    'EPR=qa.autoReject(EPR)' ...
                ], ...
                noise_detection_method, ...
                noise_detection_thresh ...
            );
            eprecorder_add_history(app.EPR,history,'Noise detection');
        end
    end

    % Response related action methods
    methods(Access=public)
        function setResponseTimeWin(app,chan_num,time_win,stimulus_num)
            % Set the time window within which response should lie.
            % [INPUT]
            % chan_num int: The channel number
            % time_win array: A 2 element vector where index 1 has the time
            %   window start and index 2 has the time window end.
            % stimulus_num int: Stimulus number. Default is the primary
            %   stimulus number.
            if nargin <4
                stimulus_num=1;
            end
            app.EPR=eprecorder_response.setTimeWin(app.EPR,chan_num,time_win,stimulus_num);

            % Add history
            history=sprintf([ ...
                    'EPR=eprecorder_response.setTimeWin(EPR,%g,%g,%g);\n ' ...
                ], ...
                chan_num,time_win,stimulus_num ...
            );
            eprecorder_add_history(app.EPR,history,'Noise rjection');
        end
        function setManualResponseTime(app,chan_num,epoch_num,manual_time,stimulus_num)
            % Set the onset and stop time of a response.
            % [INPUT]
            % chan_num int: The channel number
            % epoch_num int: The epoch number
            % manual_time array: A 2 element vector where index 1 has the 
            %   onset time and index 2 has the stop time of th eresponse
            %   for the given channel and epoch. 
            % stimulus_num int: Stimulus number. Default is the primary
            %   stimulus number.
            if nargin <5
                stimulus_num=1;
            end
            app.EPR=eprecorder_response.setManualOnsetTime( ...base
                app.EPR,chan_num,epoch_num,manual_time(1),stimulus_num ...
                );
            app.EPR=eprecorder_response.setManualStopTime( ...
                app.EPR,chan_num,epoch_num,manual_time(2),stimulus_num ...
                );

            % Add history
            history=sprintf([ ...
                    'EPR=eprecorder_response.setManualOnsetTime(EPR,%g,%g,%g,%g);\n ' ...
                    'EPR=eprecorder_response.setManualStopTime(EPR,%g,%g,%g,%g);'...
                ], ...
                chan_num,epoch_num,manual_time(1),stimulus_num, ...
                chan_num,epoch_num,manual_time(2),stimulus_num...
            );
            eprecorder_add_history(app.EPR,history,'Noise rjection');
        end
        function detectResponseFeatures( ...
                app,auto_change_time_method,auto_change_time_method_data, ...
                auto_presence_method,auto_presence_method_data, ...
                presence_detection_thresh,chan_nums,epoch_nums)
            % Detect all response features.
            % [INPUT]
            % auto_change_time_method string: Automatic response time
            %   detection method.
            % auto_change_time_method_data Cell: Cell of 2-element cells of 
            %   name value pairs of data required by change time detection 
            %   method specified.
            % auto_presence_method string: Automatic response presence
            %   detection method.
            % auto_presence_method_data Cell: Cell of 2-element cells of 
            %   name value pairs of data required by presence detection 
            %   method specified.
            % presence_detection_thresh double: The number of feature standard
            %   daviations or errors above baseline at which response is
            %   detected. 
            % chan_nums int|array<int>|[]: The channel number/s to consider. 
            % epoch_nums int|array<int>|[]: The epoch number/s of the trial/s.

            if nargin < 7 
                chan_nums=[];
            end
            if nargin <8 
                epoch_nums=[];
            end

            fprintf(['Operations to perform:\n' ...
                'autoDetectOnsetTimes\n ' ...
                'autoDetectStopTimes\n detectPresence\n ' ...
                'detectPeak2peaks\n detectArea\n']);
            response=eprecorder_response( ...
                auto_change_time_method,auto_change_time_method_data, ...
                auto_presence_method,auto_presence_method_data);
            app.EPR=response.autoDetectOnsetTimes(app.EPR,chan_nums,epoch_nums);
            app.EPR=response.autoDetectStopTimes(app.EPR,chan_nums,epoch_nums);
            app.EPR=response.detectPresence(app.EPR,presence_detection_thresh,chan_nums,epoch_nums);
            app.EPR=response.detectPeak2peaks(app.EPR,chan_nums,epoch_nums);
            app.EPR=response.detectArea(app.EPR,chan_nums,epoch_nums);
            app.redrawEpoch();

            % Add history
            history=sprintf([ ...
                    'response=eprecorder_response(%s,%s,%s,%s);\n ' ...
                    'EPR=response.autoDetectOnsetTimes(EPR,%s,%s);\n' ...
                    'EPR=response.autoDetectStopTimes(EPR,%s,%s));\n' ...
                    'EPR=response.detectPresence(EPR,%s,%s,%s));\n' ...
                    'EPR=response.detectPeak2peaks(EPR,%s,%s));\n' ...
                    'EPR=response.detectArea(EPR,%s,%s));' ...
                ], ...
                auto_change_time_method,methodData2String(auto_change_time_method_data),...
                auto_presence_method,methodData2String(auto_presence_method_data),...
                mat2str(chan_nums),mat2str(epoch_nums),...
                mat2str(chan_nums),mat2str(epoch_nums),...
                presence_detection_thresh,mat2str(chan_nums),mat2str(epoch_nums), ...
                mat2str(chan_nums),mat2str(epoch_nums),...
                mat2str(chan_nums),mat2str(epoch_nums)...
            );
            eprecorder_add_history(app.EPR,history,'Detect response features');

            function s=methodData2String(method_data)
                % convert method data to string
                % [INPUT]
                % method_data cell: cell of cell of key-value paires
                % [OUTPUT]
                % s char: A string representation of the method data that
                %   can be executed in matlab to get back the method data 
                s=[];
                for n=1:length(method_data)
                    key=method_data{n}{1};
                    val=method_data{n}{2};
                    if isnumeric(val)
                        val=mat2str(val);
                    elseif ischar(val) || isstring(val)
                        val=char(["'",val,"'"]);
                    end
                    s=[s,char(sprintf("{'%s',%s}",key,val)),char(',')];
                end
                s=strip(s,'right',',');
                s=char(['{',s,'}']);
            end
        end
        function responseFeatureDetectionMetrics(app)
            % Show auto detection matrices. Currently the result is
            % exported to base workspace as 'app_metrics'.
            [results,summary]=eprecorder_response.autoDectectionMetrics(app.EPR);
            app_metrics.results=results;
            app_metrics.summary=summary;
            assignin('base','app_metrics',app_metrics);
            msgbox('Exported to base workspace as app_metrics','modal');

        end
        function openResponseSettingsFigure(app,chan_num,epoch_num)
            % Open the response settings window to edit the channel/epoch
            % response settings and manual detection.
            % [INPUT]
            % chan_num int: The channel number
            % epoch_num int: The epoch number
            % labelling bool: When true data labelling mode is activated.
            if nargin<3
                epoch_num=app.currentEpochNumber;
            end


            fig=uifigure("Name",'Response settings', ...
                "Tag",app.uiTags_ResponseSettingsFigure);
            figGrid=uigridlayout(fig,[3,2]);
            figGrid.RowHeight = {'2x','1x',22};

            channel_name=app.channelNumber2Name(chan_num);
            channel_name=sprintf("Channel %g. %s",chan_num,channel_name);

            %% Axes
            ax=uiaxes(figGrid);
            ax.Layout.Column = [1 2];
            title(ax,channel_name);
            ylabel(ax,app.EPR.channelUnits{chan_num})
            xlabel(ax,'ms')
            showData();


            % To force indicators to fill the axes nicely on initially, we
            % can do a workaround by plotting the data again after
            % calling drawnow so that axes properties that indicators need
            % may become available
            drawnow;
            showData();
            
            %% Channel
            is_chan_epoch_rejected=app.isEpochEffectivelyRejected(chan_num,epoch_num);
            
            stimulus_nums=eprecorder_stimulus_numbers(app.EPR);
            time_wins=nan(length(stimulus_nums),2);
            for stimulus_num=stimulus_nums
                time_wins(stimulus_num,:)=eprecorder_response.getTimeWin(app.EPR,chan_num,stimulus_num);
            end
            channelPanel=uipanel(figGrid,"Title",channel_name);
            channelPanelGrid=uigridlayout(channelPanel,[2 2], ...
                "RowHeight",{22,22,22});
            uilabel(channelPanelGrid,"HorizontalAlignment",'right', ...
                "Text","Response time window(ms)", ...
                "Tooltip","The time(ms) window within which the response lie for each stimulus e.g for 2 stimuli:[8,45;58,90]. Unset an entry with NaN");
            TimeWinEdit=uieditfield(channelPanelGrid,'text', ...
                "Value",mat2str(time_wins), ...
                "Tooltip","The time(ms) window within which the response lie for each stimulus e.g for 2 stimuli:[8,45;58,90]. Unset an entry with NaN"...
                );
            RejectChannelEpochCheckbox=uicheckbox(channelPanelGrid, ...
                "Text","Reject this epoch (for this channel only)",...
                "Value",double(is_chan_epoch_rejected), ...
                "Tooltip","Tick this box to reject this epoch, for this channel only");
            RejectChannelEpochCheckbox.Layout.Column=[1,2];
            
            KeyLabel=uilabel(channelPanelGrid,"Text","*'Effective " + ...
                "response region' i.e. the shaded region on the plot" + ...
                " is the only region used to compute response features" + ...
                " such as peak2peak and area.", ...
                "FontSize",6,"WordWrap","on");
            KeyLabel.Layout.Column=[1,2];
            
            %% Epoch
            response_presences=[];
            for stimulus_num=stimulus_nums
                response_presences(end+1)=eprecorder_response.getManualPresence(app.EPR,chan_num,epoch_num,stimulus_num);
            end

            stimulus_code=app.eventCode(epoch_num);
            

            manual_times_str=cell(1,length(stimulus_nums));
            for stimulus_num=stimulus_nums

                start_t=eprecorder_response.getManualOnsetTime( ...
                    app.EPR,chan_num,epoch_num,stimulus_num);
                stop_t=eprecorder_response.getManualStopTime( ...
                    app.EPR,chan_num,epoch_num,stimulus_num);
                
                manual_times_str{stimulus_num}=strrep(mat2str([start_t,stop_t]),'NaN','auto');
            end


            epochPanel=uipanel(figGrid, ...
                "Title",sprintf("Epoch #%g - Event: %g",epoch_num,stimulus_code));
            epochPanelGrid=uigridlayout(epochPanel,[3 2], ...
                "RowHeight",{22,22,22});
            uilabel(epochPanelGrid,"HorizontalAlignment",'right', ...
                "Text","Manual response time(ms)", ...
                "Tooltip","Enter the manually determined time(ms)");
            ManualTimesTextarea=uitextarea(epochPanelGrid,...
                "Value",manual_times_str, ...
                "Tooltip","Enter the manually determined time(ms)" + ...
                " at which the responses start and stop. One 2-element row([start,stop]) per stimulus in the stimulus order. Set an entry to 'auto' to use an automatically detected value"...
                );
            ManualTimesTextarea.Layout.Row=[1,2];
            

            uilabel(epochPanelGrid,"HorizontalAlignment",'right', ...
                "Text","Manual response presence", ...
                "Tooltip","Manual response presence");
            ResponsePresenceEdit=uieditfield(epochPanelGrid, ...
                "Value",strrep(mat2str(response_presences),'NaN','auto'), ...
                "Tooltip","Enter the manually determined status of the response" + ...
                " for each stimulus number. e.g for 3 stimuli, [1,0,auto] " + ...
                "means that the first response is present, the second " + ...
                "is missing, and the third uses automatic detection result");


            %% Footer
            ApplyButton=uibutton(figGrid,"Text","Apply", ...
                "ButtonPushedFcn",@ApplyButtonPushed);
            ApplyButton.Layout.Column=[1,2];


            function showData()
                % A helper to plot the data on the internal axis.
                cla(ax);
                app.plotEpoch(ax,chan_num,epoch_num);
                legend(ax)
                
            end

            %% Internal callbacks
            function ApplyButtonPushed(source,event)


                % Channel________________________
                values=str2num(TimeWinEdit.Value);
                for stim_num=1:size(values,1)
                    app.setResponseTimeWin(chan_num,values(stim_num,:),stim_num);
                end

                app.manualReject(chan_num,epoch_num,~~RejectChannelEpochCheckbox.Value);


                % Epoch___________________________
                % Manual times
                times_strs=strrep(ManualTimesTextarea.Value,'auto','NaN');
                for stim_num=1:length(times_strs)
                    app.setManualResponseTime(chan_num,epoch_num,str2num(times_strs{stim_num}),stim_num);
                end

                % Presence
                presences=strrep(ResponsePresenceEdit.Value,'auto','NaN');
                presences=str2num(presences);
                for stim_num=1:length(presences)
                    app.setResponseManualPresence(chan_num,epoch_num, ...
                        presences(stim_num),stim_num);
                end


                % Update plot
                app.redrawEpoch();
                showData();
            end
        end
        function openLabelFigure(app,chan_num,epoch_num)
            % Open the response settings window to edit the channel/epoch
            % response settings and manual detection.
            % [INPUT]
            % chan_num int: The channel number
            % epoch_num int: The epoch number

            if nargin<3
                epoch_num=app.currentEpochNumber;
            end

            %% Key
            [labels,colors]=eprecorder_label.getFields();
            colorKeys={
                'pre stimulus '
                'stimulus artefact'
                'pre response background'
                'response'
                'post response background'
            };

            fig=uifigure("Name",'Data label', ...
                "Tag",app.uiTags_LabelFigure);
            fig.CloseRequestFcn=@closeFig;


            figGrid=uigridlayout(fig,[2,2]);
            figGrid.RowHeight = {'2x','1x'};

            channel_name=app.channelNumber2Name(chan_num);
            channel_name=sprintf("Channel %g. %s",chan_num,channel_name);

            %% Axes
            ax=uiaxes(figGrid);
            ax.Layout.Column = [1 2];
            title(ax,channel_name);
            ylabel(ax,app.EPR.channelUnits{chan_num})
            xlabel(ax,'ms')


            % Raw classification result
            axisDataPanel=uipanel(figGrid, 'Title', 'Plot data');
            axisDataPanelGrid = uigridlayout(axisDataPanel);
            rawClassifierResultCheckBox=uicheckbox(axisDataPanelGrid, ...
                "Value",0,"Text","Raw classifier result", ...
                "WordWrap","on",...
                "Tooltip","Select to overlay the result of the currently selected classifier over the plot",...
                "ValueChangedFcn",@toggleRawClassifierResultValueChanged);

            showData();
            
            %
            

            % PROMPT: Create matlab uilabel for each item in colorKeys where
            % each entry is the label text and the corresponding item in colors is the background
            % color. Add the labels in a panel with a grid layout and
            % title=Key and parent grid=figGrid.
            panel = uipanel(figGrid, 'Title', 'Key');
            %panel.Layout.Column = [1 2];
            panelLayout = uigridlayout(panel);
            
            % Iterate through the keys list
            for i = 1:numel(colorKeys)
                % Extract the label text and background color from the current key
                labelText = colorKeys{i};
                bgColor = colors{i};
            
                % Create a uilabel with the extracted text
                label = uilabel(panelLayout, 'Text', labelText);
                
                % Set the background color of the label
                label.BackgroundColor = bgColor;
                label.FontColor='white';
            end
            
            % Set the grid layout dimensions for the panel
            panelLayout.RowHeight = {'fit'};
            panelLayout.ColumnWidth = {'1x'};

            
            %%
            % To force indicators to fill the axes nicely on initially, we
            % can do a workaround by plotting the data again after
            % calling drawnow so that axes properties that indicators need
            % may become available
            drawnow;
            showData();
            

            function showData()
                % A helper to plot the data on the internal axis.
                cla(ax);
                app.plotEpoch(ax,chan_num,epoch_num,{'effective-response-times','response-features'});
                if(rawClassifierResultCheckBox.Value)
                    showRawClassifierResult();
                end
                legend(ax);

                activateLabellingMode();
            end

            function showRawClassifierResult()
                % Show the result of classification on the plot.
                % Examine the result of a classifier on a given data.

                toggle_text=rawClassifierResultCheckBox.Text;
                rawClassifierResultCheckBox.Text="Working...";
                rawClassifierResultCheckBox.Enable="off";
                drawnow;
                lineObj=findobj(ax,'Tag','channel-data');
                %data=eprecorder_get_epoch_data(app.EPR,chan_num,epoch_num);
                classifier=mepclassifier.ClassifierManager().classifier(app.MEPClassifierDropDown.Value);
                [~,~,preds]=classifier.classify(lineObj.YData',app.EPR.Fs);
                rawClassifierResultCheckBox.Enable="on";
                rawClassifierResultCheckBox.Text=toggle_text;
                
                hold on
                %plot(ax,data,'displayname','signal')
                
                
                for k=1:size(preds,2)
                    plot(ax,lineObj.XData,preds(:,k),'displayname',['Pred column' mat2str(k) ' (classifier:' classifier.getDriver() ')']);
                    %plot(ax,lineObj.XData,preds(:,2),'displayname',['Stim. artefact score (classifier:' classifier.getDriver() ')']);
                    %plot(ax,lineObj.XData,preds(:,3),'displayname',['Response score (classifier:' classifier.getDriver() ')']);
                end
                hold off
            end

            function activateLabellingMode()
                % Activate the mode to label data
                
                stim_nums=eprecorder_stimulus_numbers(app.EPR);
                
                %labels=eprecorder_label.getFields();

                c=0;
                for sn=stim_nums
                    for label_n=1:length(labels)
                        c=c+1;
                        times(c,1)=eprecorder_label.getTime(labels{label_n}{1},app.EPR,chan_num,epoch_num,sn);
                        times(c,2)=eprecorder_label.getTime(labels{label_n}{2},app.EPR,chan_num,epoch_num,sn);
                    end
                end

                
                function callback(updatedTimes)
                    c2=0;
                    for sn2=stim_nums
                        for label_n2=1:length(labels)
                            c2=c2+1;
                            app.EPR=eprecorder_label.setTime(labels{label_n2}{1},app.EPR,chan_num,epoch_num,updatedTimes(c2,1),sn2);
                            app.EPR=eprecorder_label.setTime(labels{label_n2}{2},app.EPR,chan_num,epoch_num,updatedTimes(c2,2),sn2);
                        end
                    end
                end
                
                data=eprecorder_get_epoch_data(app.EPR,chan_num,epoch_num);
                data_std=std(data);
                y_data=[min(data)-data_std,max(data)+data_std];

                file_path=fileparts(mfilename('fullpath'));
                file_path=fullfile(file_path,'dragndrop');
                addpath(file_path);
                dragRectangles(times,ax,fig,@callback,2,y_data,colors);
            end

            function toggleRawClassifierResultValueChanged(source,event)
                % React to chan
                showData();
            end
            function closeFig(src, event)
                % Close the figure and run scripts.
                delete(fig);
                app.redrawEpoch();
                
            end

            
        end
        function plotRecruitmentCurve(app,chan_nums,stimulus_codes,feature,exclude_no_response,exclude_reject,stimulus_nums)
            % Plot response recruitment curve.
            % [INPUT]
            % chan_nums int|array<int>: Channel numbers.
            % stimulus_codes double|array<double>: The event code/s of
            %   interest.
            % feature string: The features {peak2peak, area,
            %   onset_time,time_width}.
            % exclude_no_presence boolean: If true only epochs with response
            %   will be considered. 
            % exclude_reject boolean: If true only epochs not rejected
            %   will be considered.
            % stimulus_nums int|array<int>: The stimulus numbers. The default is the
            %   number for primary stimulus.
            if nargin < 7
                stimulus_nums =1;
            end
            
            rc=eprecorder_recruitment_curve(chan_nums,stimulus_codes, ...
                feature,exclude_no_response,exclude_reject);
            rc.stimulusNums=stimulus_nums;
            rc.plot(app.EPR);
            
            % Add history
            history=sprintf(['rc=eprecorder_recruitment_curve(%g,%g,%s,%g,%g);\n ' ...
                'rc.stimulusNums=;\n%g' ...
                'rc.plot(EPR)'],chan_nums,stimulus_codes,feature, ...
                exclude_no_response,exclude_reject,stimulus_nums);
            eprecorder_add_history(app.EPR,history,'Recruitment curve');

        end
        function recruitmentCurveDlg(app)
            % Open the dialog for computing recruitment curve.
            fig=uifigure("Name",'Recruitment curve', ...
                "Tag",app.uiTags_RecruitmentCurveDlgFigure);
            figGrid=uigridlayout(fig,[6,2]);
            figGrid.RowHeight = {44,44,44,44,44};

            all_chan_nums=1:length(app.EPR.channelNames);
            all_stimulus_codes=unique(eprecorder_get_epoch_stimulus_code(app.EPR),"stable");

            stimulus_nums_all=eprecorder_stimulus_numbers(app.EPR);

            uilabel(figGrid,"HorizontalAlignment",'right', ...
                "Text","Feature");
            FeatureDropdown=uidropdown(figGrid,...
                "Items",{'peak2peak','area','onset_time','time_width'},...
                "Value",'peak2peak', ...
                "Tooltip","The feature to compute"...
                );

            uilabel(figGrid,"HorizontalAlignment",'right', ...
                "Text","Channels");
            ChannelsEdit=uieditfield(figGrid,'text', ...
                "Value",mat2str(all_chan_nums), ...
                "Tooltip","The number for the channels to plot"...
                );

            uilabel(figGrid,"HorizontalAlignment",'right', ...
                "Text","Stimulus codes(Intensities)");
            StimulusCodeEdit=uieditfield(figGrid,'text', ...
                "Value",mat2str(all_stimulus_codes), ...
                "Tooltip","The stimulus codes which are taken as the" + ...
                " intensities to plot"...
                );
            
            uilabel(figGrid,"HorizontalAlignment",'right', ...
                "Text","Stimulus numbers");
            StimulusNumbersEdit=uieditfield(figGrid,...
                "Value",mat2str(stimulus_nums_all),...
                "Tooltip","The stimulus numbers within a trial: " + ...
                "1=>primary while others are sencondary"...
                );

            ExludeNoResponseCheckbox=uicheckbox(figGrid, ...
                "Text","Exclude no response",...
                "Value",0, ...
                "Tooltip","Tick this box to exclude epochs marked" + ...
                " as having no response");

            ExludeRejectCheckbox=uicheckbox(figGrid, ...
                "Text","Exclude reject",...
                "Value",1, ...
                "Tooltip","Tick this box to exclude rejected epochs");

            %% Footer
            PlotButton=uibutton(figGrid,"Text","Plot recruitment curve", ...
                "ButtonPushedFcn",@PlotButtonPushed);
            PlotButton.Layout.Column=[1,2];

            %% Internal callbacks
            function PlotButtonPushed(source,event)
                feature=FeatureDropdown.Value;
                chan_nums=str2num(ChannelsEdit.Value);
                stimulus_codes=str2num(StimulusCodeEdit.Value);
                exclude_no_response=ExludeNoResponseCheckbox.Value;
                exclude_reject=ExludeRejectCheckbox.Value;
                stimulus_nums=str2num(StimulusNumbersEdit.Value);
                app.plotRecruitmentCurve(chan_nums,stimulus_codes, ...
                    feature,exclude_no_response,exclude_reject,stimulus_nums);
            end
        end
        function plotAverage(app,chan_nums,stimulus_code,time_win,exclude_no_response,exclude_reject)
            % Plot response average.
            % [INPUT]
            % chan_nums int|array<int>: Channel numbers.
            % stimulus_code []|double: The event code of interest. Ignored unless
            %   epoch_nums=[].
            % time_win []|array<double>: The time(ms) range within an epoch.
            % exclude_no_presence boolean: If true only epochs with response
            %   will be considered. 
            % exclude_reject boolean: If true only epochs not rejected
            %   will be considered. 
            eprecorder_response.average(app.EPR,chan_nums,[], ...
                stimulus_code,time_win,exclude_no_response,exclude_reject);
            
            
            % Add history
            history=sprintf(['eprecorder_response.average(%g,%g,%g,%g,%g);\n ' ...
                'rc.plot(EPR)'],chan_nums,stimulus_code,time_win, ...
                exclude_no_response,exclude_reject);
            eprecorder_add_history(app.EPR,history,'Evoked potential');

        end
        function averageDlg(app)
            % Open the dialog for computing average.
            fig=uifigure("Name",'Average', ...
                "Tag",app.uiTags_AverageDlgFigure);
            figGrid=uigridlayout(fig,[5,2]);
            figGrid.RowHeight = {44,44,44,44,44};

            all_chan_nums=1:length(app.EPR.channelNames);

            all_stimulus_codes=unique(eprecorder_get_epoch_stimulus_code(app.EPR),"stable");
            all_stimulus_codes_str=cellfun(@(s){mat2str(s)},num2cell(all_stimulus_codes));
            all_stimulus_codes_str=cat(2,'All',all_stimulus_codes_str);

            all_time_win=[app.EPR.times(1),app.EPR.times(end)];
            uilabel(figGrid,"HorizontalAlignment",'right', ...
                "Text","Channels");
            ChannelsEdit=uieditfield(figGrid,'text', ...
                "Value",mat2str(all_chan_nums), ...
                "Tooltip","The number for the channels to plot"...
                );

            uilabel(figGrid,"HorizontalAlignment",'right', ...
                "Text","Stimulus code");
            StimulusCodeEdit=uidropdown(figGrid,...
                "Items",all_stimulus_codes_str,...
                "Value",'All', ...
                "Tooltip","Restrict to a specific stimulus code"...
                );

            uilabel(figGrid,"HorizontalAlignment",'right', ...
                "Text","Time win (ms)");
            TimeWinEdit=uieditfield(figGrid,'text', ...
                "Value",mat2str(all_time_win), ...
                "Tooltip","Two-element start and stop time within an epochs"...
                );

            ExludeNoResponseCheckbox=uicheckbox(figGrid, ...
                "Text","Exclude no response",...
                "Value",0, ...
                "Tooltip","Tick this box to exclude epochs marked" + ...
                " as having no response");

            ExludeRejectCheckbox=uicheckbox(figGrid, ...
                "Text","Exclude reject",...
                "Value",1, ...
                "Tooltip","Tick this box to exclude rejected epochs");

            %% Footer
            PlotButton=uibutton(figGrid,"Text","Plot average", ...
                "ButtonPushedFcn",@PlotButtonPushed);
            PlotButton.Layout.Column=[1,2];

            %% Internal callbacks
            function PlotButtonPushed(source,event)
                chan_nums=str2num(ChannelsEdit.Value);
                stimulus_code=str2num(StimulusCodeEdit.Value);
                if strcmp(stimulus_code,'All')
                    stimulus_code=[];
                end
                time_win=str2num(TimeWinEdit.Value);
                exclude_no_response=ExludeNoResponseCheckbox.Value;
                exclude_reject=ExludeRejectCheckbox.Value;
                app.plotAverage(chan_nums,stimulus_code,time_win, ...
                    exclude_no_response,exclude_reject)
            end
        end

    end

    % Response normalisation
    methods(Access=private)
        function updateResponseNormalisationUi(app)
            % Update the response normalisation UI.
            mvc_rms_norms=eprecorder_response_norm.get(app.EPR,'mvc_rms');
            p2p_norms=eprecorder_response_norm.get(app.EPR,'max_response_peak2peak');
            app.ResponseNormalisationMVCrmsTextArea.Value=mat2str(mvc_rms_norms');
            app.ResponseNormalisationMaxp2pTextArea.Value=mat2str(p2p_norms');
        end

        function manualSaveResponseNormalisationUi(app)
            % Save the current content of normalisation UI.
            mvc_rms_norms=str2num(str2mat(app.ResponseNormalisationMVCrmsTextArea.Value));
            p2p_norms=str2num(str2mat(app.ResponseNormalisationMaxp2pTextArea.Value));

            app.EPR=eprecorder_response_norm.set(app.EPR,'mvc_rms',[],mvc_rms_norms);
            app.EPR=eprecorder_response_norm.set(app.EPR,'max_response_peak2peak',[],p2p_norms);
       end

    end

    % General methods
    methods(Access=private)
        function bootData(app,EPR)
            % Set boot up the app with initial data.
            % [INPUT]
            % EPR: The EPR data structure
            
            app.setCurrentDataset(app.addDataset(EPR));
            

            %if(app.isEpoched())                
                app.gotoEpoch(1);
            %end
        end

        function dataset_idx=addDataset(app,EPR)
            % Add a new dataset
            % [INPUT]
            % EPR: The EPR data structure
            % [OUTPUT]
            % dataset_idx int: The index of the new dataset in allEPR.
            app.allEPR{end+1}=EPR;
            dataset_idx=length(app.allEPR);

            app.updateDatasetDropdown();   
        end
        function removeDataset(app,dataset_idx)
            % The the current dataset
            % [INPUT]
            % dataset_idx int: The index of the dataset to remove
            
            app.currentSet=0;
            app.allEPR(dataset_idx)=[];

            app.updateDatasetDropdown(); 
            
            if(~isempty(app.allEPR))
                app.setCurrentDataset(1);
            else
                app.clearAxes();
            end
        
        end
        function removeCurrentDataset(app)
            % Add a new dataset
            if(app.currentSet~=0)
                app.removeDataset(app.currentSet);
            end
        end
        function setCurrentDataset(app,dataset_idx)
            % Set the current dataset
            % [INPUT]
            % dataset_idx int: The index of the dataset to set as current.

            app.syncCurrentset();

            app.currentSet=dataset_idx;
            app.EPR=app.allEPR{app.currentSet};

            app.appSubname(app.EPR.fn2);

            app.prepareData();
            app.showInfo();
            app.visualise();
            
            app.redrawEpoch(1);
            app.applyYLimit();

            % Sync with Dataset dropdown list
            app.DatasetDropDown.Value=app.currentSet;

            % Show normalisations
            app.updateResponseNormalisationUi();

            % Update meta UI
            app.updateMetadataUIs();
            
        end

        function syncCurrentset(app)
            % Store the current dataset to allEPR.
            if(isempty(app.EPR) || app.currentSet==0)
                return;
            end
            app.allEPR{app.currentSet}=app.EPR;
        end
        function loadData(app,filename)
            % Load data given by filename
            % [INPUT]
            % filename string: EPR data filename

            EPRData=load(filename); 
            if eprecorder_has_epoch(EPRData.EPR)
                app.setCurrentDataset(app.addDataset(EPRData.EPR));
            else
                disp('Using Continuous lab');
                eprecorder_continuous_lab(EPRData.EPR);
            end
        end
        function openData(app,filename)
            % Open data new EPR data
            % [INPUT]
            % filename string: EPR data filename/folder to load from.

            if nargin < 2
                filename=[];
            end

            default_fn=[];
            if ~isempty(filename)
                default_fn=filename;
                
            end

            if 2==exist(default_fn,'file')
                fn=default_fn;
            else
                if ~isempty(app.EPR) && isempty(default_fn)
                    default_fn=app.EPR.fn2;
                end
                [file,path] = uigetfile('.mat','Select EPR .mat file',default_fn);
                if(file==0)
                    return
                end
                fn=[path file];
                if(~exist(fn,'file'))
                    msgbox([fn, ' is not found'],'Cannot find file');
                    return;
                end
            end

            app.loadData(fn);
%             EPRData=load(fn); 
%             if eprecorder_has_epoch(EPRData.EPR)
%                 app.setCurrentDataset(app.addDataset(EPRData.EPR));
%             else
%                 disp('Using Continuous lab');
%                 eprecorder_continuous_lab(EPRData.EPR);
%             end
        end

        function saveData(app,EPR)
            % Save the the given or current data
            % [INPUT]
            % EPR struct: The main EPR data structure. The default is the
            %   current dataset.
            is_current_dataset=false;
            if nargin<2
                EPR=app.EPR;
                is_current_dataset=true;
            end

            % Priotise using the filename of the current dataset.
            
            if isempty(app.EPR)
                default_fn=EPR.fn2;
            else
                default_fn=app.EPR.fn2;
            end

            %
            [file,path] = uiputfile('.mat','Select location and enter filename',default_fn);

            if(file==0)
                return
            end

            fn=[path file];
            if(exist(fn,'file'))
                answer=questdlg([fn, ' already axists. Do you want to overwrite it?'],'Confirm overwrite');
                if ~strcmp(answer,'Yes')
                    return
                end
            end
            
            EPR.fn2=fn;
            if is_current_dataset
                app.EPR.fn2=EPR.fn2;
            end

            save(EPR.fn2,'EPR');
        end
        function updateDatasetDropdown(app)
            % Update the dataset dropdown list.
            items_data=[];
            items={};
            for n=1:length(app.allEPR)
                name='newset';
                if(isfield(app.allEPR{n},'name'))
                    name=app.allEPR{n}.name;
                end
                items{n}=sprintf('%g. %s',n,name);
                items_data(n)=n;
            end
            app.DatasetDropDown.Items=items;
            app.DatasetDropDown.ItemsData=items_data;
        end
        function appSubname(app,subname)
            % Set the subname of the app
            % [INPUT]
            % subname string: The subname.
            app.EPRecorderEpochLabUIFigure.Name=[
                    app.name,...
                    ' - ',...
                    subname
                ];
        end
    end

    % TESCS 
    methods(Access=private)
        function openTescsParametersDlg(app)
            % Open TESCS parameters identification dialogue
            delete(app.TescsParametersDlgApp);
            
            %If the current app.EPR has recent changes, the change might
            %not be present in app.allEPR; so app.allEPR much be synced
            %with the current app.EPR.
            app.syncCurrentset();

            %
            app.TescsParametersDlgApp=eprecorder_tescs_parameters(app.allEPR);
        end
    end

    % Utilities
    methods(Static,Access=private)
        function varargout=addPlot(ax,varargin)
            % A helper add a plot given in x and y to axis ax.
            % [INPUT]
            % ax: Matlab axis
            % varargin: Any other input to Matlab plot();
            % [OUTPUT]
            % Same output as Matlab plot.
            original_nextplot=ax.NextPlot;
            ax.NextPlot="add";     
            [varargout{1:nargout}]=plot(ax,varargin{:});
            ax.NextPlot=original_nextplot;
        end
        function results=array_map(input_array,callback_fcn,with_idx)
            % Process every row of an array with the given callback and
            % return the concatenated result.
            % [INPUT]
            % input_array array: A 2-D array whos rows will be processed.
            % callbacl_fcn Function: The callback that will recieve each row.
            % with_idx boolean: When true the callback function will be
            %   called with two arguments, where the first the the current
            %   row of input_array and the second is the current index. When
            %   false, only the current row will be sent.
            % [OUTPUT]
            % results cellArray: The column array of results of processing each
            % row. Has the same number of rows as input_array.

            if nargin<4
                with_idx=false;
            end

            out_size=size(input_array,1);
            results=cell(out_size,1);
            for n=1:out_size
                if(with_idx)
                    results{n}=callback_fcn(input_array(n,:),n);
                else
                    results{n}=callback_fcn(input_array(n,:));
                end
            end
        end
    end

    % Menu/Settings
    methods(Access=private)
        function openSettingsDlg(app)
            % Open settings dlg box.
             

            fig=uifigure("Name",'Settings', ...
                "Tag",app.uiTags_SettingsFigure);
            figGrid=uigridlayout(fig,[9,2]);
            

            uilabel(figGrid,"Text",'example_toggle_mode');
            exampleToggleModeDropDown = uidropdown(figGrid,"Items",{'normal','force'}, ...
                'Value',app.setting__example_toggle_mode,...
                'ValueChangedFcn',@exampleToggleModeDropDownValueChanged);
            descr=uilabel(figGrid,"Text",['<font style="color:#666; font-size:10px;"> Example description: When "force" clicking on response ' ...
                'toggle button on an axis changes the manual response ' ...
                'presence to either 0 or 1. When "normal" it is set to' ...
                ' either 1 or NaN(i.e auto). </font>']);
            descr.Interpreter="html";
            descr.Layout.Column = [1 2];
            descr.WordWrap = "on";

            function exampleToggleModeDropDownValueChanged(src,event,comp)
                app.settingExampleToggleMode(exampleToggleModeDropDown.Value);
            end
        end

        function settingExampleToggleMode(app,mode)
            % Set the corresponding setting
            % [INPUT]
            % mode string: The new mode.
            if ~any(strcmp({'normal','force'},mode))
                error(['Unknown model: ',mode])
            end
            app.setting__example_toggle_mode =mode;
        end

        function updateMetadataUIs(app)
            % Update metadata Ui
            app.StimcodetointenEditField.Value='';
            meta=eprecorder_metadata.get(app.EPR,'stim_code_to_intensity_factor');
            if ~isempty(meta)
                app.StimcodetointenEditField.Value=mat2str(meta.value);
            end
        end
    end


    % CallBack that handle dynamic component events
    methods(Access=private)
        % Button pushed function: ToggleResponsePresenceButton
        function ToggleResponsePresenceButtonPushed(app, source,event)
            epoch_num=app.currentEpochNumber;
            chan_num=source.UserData;
            stimulus_nums=eprecorder_stimulus_numbers(app.EPR);
            for stimulus_num=stimulus_nums
                presence=true;
                result=eprecorder_response.getManualPresence(app.EPR,chan_num,epoch_num,stimulus_num);
                switch(result)
                    case 0
                        presence=true;
                    case 1
                        presence=NaN;
                    otherwise % i.e NAN
                        presence=false;
                end

% DELETE (OLD IMPLEMENTATION)
%                 if app.hasResponse(chan_num,epoch_num,stimulus_num)
%                     presence=NaN;
%                     if strcmp(app.setting__response_presence_toggle_mode,'force')
%                         presence=false;
%                     end
%                 end
                app.setResponseManualPresence(chan_num,epoch_num,presence,stimulus_num);
            end
            app.redrawEpoch();
        end

        % Button pushed function
        function ToggleRejectButtonPushed(app, source,event)
            epoch_num=app.currentEpochNumber;
            chan_num=source.UserData;
            
            result=eprecorder_epoch_qa.getManualReject(app.EPR,chan_num,epoch_num);
            switch(result)
                case 0
                    manual_reject=1;
                case 1
                    manual_reject=NaN;
                otherwise % i.e NAN
                    manual_reject=0;
            end
            app.manualReject(chan_num,epoch_num,manual_reject);

            app.redrawEpoch();
        end

        % Button pushed function.
        function ResponseSettingsButtonPushed(app,source,event)
            epoch_num=app.currentEpochNumber;
            chan_num=source.UserData;
            app.openResponseSettingsFigure(chan_num,epoch_num);
        end

        % Button pushed function.
        function LabelButtonPushed(app,source,event)
            epoch_num=app.currentEpochNumber;
            chan_num=source.UserData;
            app.openLabelFigure(chan_num,epoch_num);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EPR)

            if nargin>1
                if isstring(EPR) || ischar(EPR)
                    app.openData(EPR);
                elseif isstruct(EPR)
                    if eprecorder_has_epoch(EPR)
                        disp('Initialising default data')
                        app.bootData(EPR);
                    else                    
                        disp('Switching app')
                        eprecorder_continuous_lab(EPR);
                        app.delete;
                    end
                else
                    warning('Unknow input, no data was loaded');
                end
            end
        end

        % Value changed function: ActionDropDown
        function ActionDropDownValueChanged(app, event)
            value = app.ActionDropDown.Value;
            if(strcmpi('quick recruitment curve',value))
                eprecorder_recruitment_curve_quick(app.EPR)
            end
        end

        % Value changed function: TrialDropDown
        function TrialDropDownValueChanged(app, event)
            value = app.TrialDropDown.Value;
            app.gotoEpoch(str2double(value));
        end

        % Value changed function: YLimitEditField
        function YLimitEditFieldValueChanged(app, event)
            value = app.YLimitEditField.Value;
            y1=str2num(value);
            app.yLimit=y1;
            app.applyYLimit();
            
            % Sync trigger height with axis limit
            app.redrawEpoch();
        end

        % Button pushed function: ToggleRejectEpochButton
        function ToggleRejectEpochButtonPushed(app, event)
            epoch_num=app.currentEpochNumber;
  
            app.manualReject([],epoch_num, ...
                ~any(app.isEpochEffectivelyRejected([],epoch_num)));
    
            app.redrawEpoch();
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.saveData();
            
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            app.openData();
        end

        % Value changed function: PlaybackspeedKnob
        function PlaybackspeedKnobValueChanged(app, event)
            if(~app.isPlaying())
                return;
            end
            speed = app.PlaybackspeedKnob.Value;
            app.stopPlayer();
            app.startPlayer(speed);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stopPlayer();
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.startPlayer();
        end

        % Close request function: EPRecorderEpochLabUIFigure
        function EPRecorderEpochLabUIFigureCloseRequest(app, event)
            
            
            answer=questdlg('Are you sure you want to close the app','Confirm close');
            if ~strcmp(answer,'Yes')
                return
            end
            
            % Delete player
            delete(app.playerTimer)

            % Delete TESCS param dialogue
            delete(app.TescsParametersDlgApp);

            % Delete sub figures
            uiFigures=findall(0,'Type','figure',"Tag",app.uiTags_SettingsFigure);
            delete(uiFigures);

            uiFigures=findall(0,'Type','figure',"Tag",app.uiTags_ResponseSettingsFigure);
            delete(uiFigures);

            uiFigures=findall(0,'Type','figure',"Tag",app.uiTags_LabelFigure);
            delete(uiFigures);

            uiFigures=findall(0,'Type','figure',"Tag",app.uiTags_RecruitmentCurveDlgFigure);
            delete(uiFigures);
            
            uiFigures=findall(0,'Type','figure',"Tag",app.uiTags_AverageDlgFigure);
            delete(uiFigures);

            % Delete app
            delete(app)
            
        end

        % Button pushed function: NextEpochButton
        function NextEpochButtonPushed(app, event)
            app.gotoNextEpoch();
        end

        % Value changed function: DatasetnameEditField
        function DatasetnameEditFieldValueChanged(app, event)
            value = app.DatasetnameEditField.Value;
            app.setDatasetName(value);
            app.syncCurrentset();
            app.updateDatasetDropdown();
        end

        % Value changed function: DatasetDropDown
        function DatasetDropDownValueChanged(app, event)
            value = app.DatasetDropDown.Value;
            app.setCurrentDataset(value);
        end

        % Button pushed function: RemoveCurrentDatasetButton
        function RemoveCurrentDatasetButtonPushed(app, event)
            app.removeCurrentDataset();
        end

        % Button pushed function: DetectResponseFeaturesButton
        function DetectResponseFeaturesButtonPushed(app, event)
            %app.DetectResponseFeaturesButton.Enable="off";
            old_text=app.DetectResponseFeaturesButton.Text;
            app.DetectResponseFeaturesButton.Text="Working...";
            
            %app.DetectResponseFeaturesStopButton.Enable="on";

            drawnow;


            %
            auto_change_time_method=app.AutoChangeTimeDetectionMethodDropDown.Value;
            classifier=app.MEPClassifierDropDown.Value;
            auto_change_time_method_data={{'driver',classifier}};
            if strcmp(auto_change_time_method,'findchangepts')
                % TODO: Get this method data through the GUI
                auto_change_time_method_data={{'statistic','mean'},{'MaxNumChanges',10}};
            end

            %
            auto_presence_method=app.AutoPresenceDetectionMethodDropDown.Value;
            auto_presence_method_data={{'driver',classifier}};
            if strcmp(auto_presence_method,'findchangepts')
                % TODO: Get this method data through the GUI
                auto_presence_method_data={{'statistic','mean'},{'MaxNumChanges',10}};
            end

            % Current epoch only or all epochs
            chan_nums=[];
            epoch_nums=[];
            if app.AutoDetectionCurrentEpochOnlyCheckBox.Value
                epoch_nums=app.currentEpochNumber;
            end

            %
            response_stds=app.ResponseStdsEditField.Value;
            try
                app.detectResponseFeatures(...
                    auto_change_time_method,auto_change_time_method_data, ...
                    auto_presence_method,auto_presence_method_data,...
                    response_stds,chan_nums,epoch_nums);
            catch err
                %app.DetectResponseFeaturesButton.Enable="on";
                app.DetectResponseFeaturesButton.Text=old_text;
                rethrow(err);
            end

            %app.DetectResponseFeaturesButton.Enable="on";
            app.DetectResponseFeaturesButton.Text=old_text;
            %app.DetectResponseFeaturesStopButton.Enable="off";

            msgbox('Successfully completed!','Response features detection','modal');
        end

        % Button pushed function: OpenRecruitmentCurveDlgButton
        function OpenRecruitmentCurveDlgButtonPushed(app, event)
            app.recruitmentCurveDlg();
        end

        % Button pushed function: OpenAverageDlgButton
        function OpenAverageDlgButtonPushed(app, event)
            app.averageDlg();
        end

        % Button pushed function: PreviousEpochButton
        function PreviousEpochButtonPushed(app, event)
             app.gotoPreviousEpoch();
        end

        % Button pushed function: AutoRejectButton
        function AutoRejectButtonPushed(app, event)
            
            app.AutoRejectButton.Enable="off";
            old_text=app.AutoRejectButton.Text;
            app.AutoRejectButton.Text="Working...";

            drawnow;
            
            noise_detection_method=app.AutoRejectMethodDropDown.Value;
            detection_stds=app.AutoRejectStdsEditField.Value;
            try
                app.autoReject(noise_detection_method,detection_stds);
            catch err
                app.AutoRejectButton.Enable="on";
                app.AutoRejectButton.Text=old_text;
                rethrow(err);
            end

            app.AutoRejectButton.Enable="on";
            app.AutoRejectButton.Text=old_text;
            msgbox('Successfully completed!','Epoch noise detection','modal');
            
        end

        % Button pushed function: TescsParametersButton
        function TescsParametersButtonPushed(app, event)
             app.openTescsParametersDlg();
        end

        % Button pushed function: LabelExportButton
        function LabelExportButtonPushed(app, event)
            
            default_fn=app.temp.label_last_export_filename;
            if isempty(default_fn) && ~isempty(app.EPR.fn2)
                default_fn=app.EPR.fn2;
                [fd,fname,~]=fileparts(default_fn);
                default_fn=fullfile(fd,[fname,'_labelled']);
            end

            %
            [file,path] = uiputfile('.csv','Select location and enter filename',default_fn);

            if(file==0)
                return
            end

            fn=[path file];
            if(exist([fn,'.csv'],'file'))
                answer=questdlg([[fn,'.csv'], ' already axists. Do you want to overwrite it?'],'Confirm overwrite');
                if ~strcmp(answer,'Yes')
                    return
                end
            end

            exclude_labels=app.LabelExportExcludeLabelsEditField.Value;
            exclude_labels = strsplit(exclude_labels, ',');

            minSegLen=app.LabelExportMinSegmentSampleLenEditField.Value;

            [data,targets]=eprecorder_label.export(app.EPR,fn,exclude_labels,minSegLen);
            
            % Visualise
            figure,plot(data),hold on,plot(targets(:,1:3));hold off;
            legend({'Data','Background','Artefact','Response'})

            %
            app.temp.label_last_export_filename=fn;
        end

        % Value changed function: IndicatormodeDropDown
        function IndicatormodeDropDownValueChanged(app, event)
            value = app.IndicatormodeDropDown.Value;
            app.indicatorMode=value;
            app.redrawEpoch();
        end

        % Value changed function: AutoPresenceDetectionMethodDropDown
        function AutoPresenceDetectionMethodDropDownValueChanged(app, event)
            value = app.AutoPresenceDetectionMethodDropDown.Value;
            
            % Set reset the value of the detection threshold
            switch(value)
                case 'findchangepts'
                    app.ResponseStdsEditField.Enable="on";
                case 'mep_classifier'
                    app.ResponseStdsEditField.Enable="off";
            end
        end

        % Button pushed function: DetectResponseFeaturesStopButton
        function DetectResponseFeaturesStopButtonPushed(app, event)
            msgbox('Stop detection!','To stop response features detection, follow MATLAB method of terminating a script','modal');
        end

        % Drop down opening function: MEPClassifierDropDown
        function MEPClassifierDropDownOpening(app, event)
            app.loadMEPClassifiers();
            
            classifiers={};
            for c=1:length(app.MEPClassifiers)
                classifiers{c}=app.MEPClassifiers(c).driver;
            end
            app.MEPClassifierDropDown.Items=classifiers;
        end

        % Button pushed function: GuessLabelTimesButton
        function GuessLabelTimesButtonPushed(app, event)
            answer=questdlg('This will overite existing label times. Do you want to overwrite it?','Confirm label time overwrite');
            if ~strcmp(answer,'Yes')
                return
            end
            
            txt=app.GuessLabelTimesButton.Text;
            app.GuessLabelTimesButton.Text="Working...";
            app.EPR=eprecorder_label.guessTimes(app.EPR);
            app.GuessLabelTimesButton.Text=txt;

            app.redrawEpoch();
        end

        % Button pushed function: LabelTimesFromeResponseButton
        function LabelTimesFromeResponseButtonPushed(app, event)
            
            answer=questdlg('This will overite existing label times. Do you want to overwrite it?','Confirm label time overwrite');
            if ~strcmp(answer,'Yes')
                return
            end
            
            txt=app.LabelTimesFromeResponseButton.Text;
            app.LabelTimesFromeResponseButton.Text="Working...";

            app.EPR=eprecorder_label.copyTimesFromResponse(app.EPR);

            app.LabelTimesFromeResponseButton.Text=txt;
            app.redrawEpoch();
        end

        % Drop down opening function: ResponseNormalisationTypeDropDown
        function ResponseNormalisationTypeDropDownOpening(app, event)
            
            app.ResponseNormalisationTypeDropDown.Items=eprecorder_response_norm().types;
        end

        % Button pushed function: ResponseNormalisationSourcedatasetButton
        function ResponseNormalisationSourcedatasetButtonPushed(app, event)
            [file,path] = uigetfile({'*.mat'},'Select a file',app.EPR.fn2);
            sourceDataset=load(fullfile(path,file));

            chan_nums=1:length(sourceDataset.EPR.channelNames);
            chan_nums=inputdlg('Enter channels','Normalisation',1, ...
                        {mat2str(chan_nums)});

            chan_nums=str2num(chan_nums{1});

            type=app.ResponseNormalisationTypeDropDown.Value;
            app.EPR=eprecorder_response_norm.setFromDataset(app.EPR,type,chan_nums,sourceDataset.EPR);
            app.updateResponseNormalisationUi();
        end

        % Value changed function: ResponseNormalisationMVCrmsTextArea
        function ResponseNormalisationMVCrmsTextAreaValueChanged(app, event)
            value = app.ResponseNormalisationMVCrmsTextArea.Value;
            app.manualSaveResponseNormalisationUi();
        end

        % Value changed function: ResponseNormalisationMaxp2pTextArea
        function ResponseNormalisationMaxp2pTextAreaValueChanged(app, event)
            value = app.ResponseNormalisationMaxp2pTextArea.Value;
            app.manualSaveResponseNormalisationUi();
        end

        % Button pushed function: ToworkspaceButton
        function ToworkspaceButtonPushed(app, event)
            assignin('base','app_EPR',app.EPR);
        end

        % Button pushed function: ResponseFeatureDetectionMetricsButton
        function ResponseFeatureDetectionMetricsButtonPushed(app, event)
            app.responseFeatureDetectionMetrics();
        end

        % Menu selected function: SettingsMenu
        function SettingsMenuSelected(app, event)
            app.openSettingsDlg();
        end

        % Button pushed function: LabelTimesFromDatasetButton
        function LabelTimesFromDatasetButtonPushed(app, event)
            
            [file,path] = uigetfile({'*.mat'},'Select a file',app.EPR.fn2);
            sourceDataset=load(fullfile(path,file));
            app.EPR=eprecorder_label.copyTimesFromDataset(app.EPR,sourceDataset.EPR);
            msgbox('Done','Copy label times from dataset','modal')
            app.redrawEpoch();

        end

        % Value changed function: StimcodetointenEditField
        function StimcodetointenEditFieldValueChanged(app, event)
            value = app.StimcodetointenEditField.Value;
            m=str2num(value);
            m=reshape(m,[],1);
            if length(app.EPR.channelNames)~=size(m,1) && numel(m)~=1
                errordlg('Data does not match number of channels','Stimulus code to intensity');
                app.updateMetadataUIs();
                return
            end
            app.EPR=eprecorder_metadata.update(app.EPR,'stim_code_to_intensity_factor',m);
            app.updateMetadataUIs();


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.EPRecorderEpochLabUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EPRecorderEpochLabUIFigure = uifigure('Visible', 'off');
            app.EPRecorderEpochLabUIFigure.Name = 'EP Recorder - Epoch Lab';
            app.EPRecorderEpochLabUIFigure.CloseRequestFcn = createCallbackFcn(app, @EPRecorderEpochLabUIFigureCloseRequest, true);
            app.EPRecorderEpochLabUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.SettingsMenu = uimenu(app.EPRecorderEpochLabUIFigure);
            app.SettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @SettingsMenuSelected, true);
            app.SettingsMenu.Text = 'Settings';
            app.GridLayout = uigridlayout(app.EPRecorderEpochLabUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.InfoBox = uilabel(app.LeftPanel);
            app.InfoBox.Text = 'Open a file to get started';
            app.ActionDropDownLabel = uilabel(app.LeftPanel);
            app.ActionDropDownLabel.Text = 'Action';
            app.ActionDropDown = uidropdown(app.LeftPanel);
            app.ActionDropDown.Items = {'Select action', 'Quick recruitment curve'};
            app.ActionDropDown.ValueChangedFcn = createCallbackFcn(app, @ActionDropDownValueChanged, true);
            app.ActionDropDown.Value = 'Select action';
            app.YLimitLabel = uilabel(app.LeftPanel);
            app.YLimitLabel.Text = 'YLimit';
            app.YLimitEditField = uieditfield(app.LeftPanel, 'text');
            app.YLimitEditField.ValueChangedFcn = createCallbackFcn(app, @YLimitEditFieldValueChanged, true);
            app.YLimitEditField.Value = '[]';
            app.SaveButton = uibutton(app.LeftPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.OpenButton = uibutton(app.LeftPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.RemoveCurrentDatasetButton = uibutton(app.LeftPanel, 'push');
            app.RemoveCurrentDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveCurrentDatasetButtonPushed, true);
            app.RemoveCurrentDatasetButton.Tooltip = {'Remove the current dataset from memory without touching the file on the disk.'};
            app.RemoveCurrentDatasetButton.Text = 'Clear';
            app.DatasetDropDownLabel = uilabel(app.LeftPanel);
            app.DatasetDropDownLabel.Text = 'Dataset';
            app.DatasetDropDown = uidropdown(app.LeftPanel);
            app.DatasetDropDown.Items = {'None'};
            app.DatasetDropDown.ValueChangedFcn = createCallbackFcn(app, @DatasetDropDownValueChanged, true);
            app.DatasetDropDown.Value = 'None';
            app.DatasetnameEditFieldLabel = uilabel(app.LeftPanel);
            app.DatasetnameEditFieldLabel.Text = 'Dataset name';
            app.DatasetnameEditField = uieditfield(app.LeftPanel, 'text');
            app.DatasetnameEditField.ValueChangedFcn = createCallbackFcn(app, @DatasetnameEditFieldValueChanged, true);
            app.TescsParametersButton = uibutton(app.LeftPanel, 'push');
            app.TescsParametersButton.ButtonPushedFcn = createCallbackFcn(app, @TescsParametersButtonPushed, true);
            app.TescsParametersButton.Tooltip = {'Merge two datasets'};
            app.TescsParametersButton.Text = 'TESCS params';
            app.IndicatormodeDropDownLabel = uilabel(app.LeftPanel);
            app.IndicatormodeDropDownLabel.Text = 'Indicator mode';
            app.IndicatormodeDropDown = uidropdown(app.LeftPanel);
            app.IndicatormodeDropDown.Items = {'normal', 'label'};
            app.IndicatormodeDropDown.ValueChangedFcn = createCallbackFcn(app, @IndicatormodeDropDownValueChanged, true);
            app.IndicatormodeDropDown.Tooltip = {'Plot indicator mode. Determines the type on indicators shown by default on the data plots.'};
            app.IndicatormodeDropDown.Value = 'normal';
            app.ToworkspaceButton = uibutton(app.LeftPanel, 'push');
            app.ToworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ToworkspaceButtonPushed, true);
            app.ToworkspaceButton.Tooltip = {'Export the current dataset to base workspace as "app_EPR"'};
            app.ToworkspaceButton.Text = 'To workspace';
            app.CenterPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.CurrentEpochInfoLabel = uilabel(app.RightPanel);
            app.CurrentEpochInfoLabel.Text = 'Open a file to get started';
            app.TrialDropDownLabel = uilabel(app.RightPanel);
            app.TrialDropDownLabel.Text = 'Trial';
            app.TrialDropDown = uidropdown(app.RightPanel);
            app.TrialDropDown.Items = {'Select'};
            app.TrialDropDown.ValueChangedFcn = createCallbackFcn(app, @TrialDropDownValueChanged, true);
            app.TrialDropDown.Value = 'Select';
            app.ToggleRejectEpochButton = uibutton(app.RightPanel, 'push');
            app.ToggleRejectEpochButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleRejectEpochButtonPushed, true);
            app.ToggleRejectEpochButton.Text = 'Toggle reject epoch';
            app.TabGroup = uitabgroup(app.RightPanel);
            app.PlayerTab = uitab(app.TabGroup);
            app.PlayerTab.Title = 'Player';
            app.PlayButton = uibutton(app.PlayerTab, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.PlayerTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PlaybackspeedKnobLabel = uilabel(app.PlayerTab);
            app.PlaybackspeedKnobLabel.Text = 'Playback speed';
            app.PlaybackspeedKnob = uiknob(app.PlayerTab, 'discrete');
            app.PlaybackspeedKnob.Items = {'Slow', 'Normal', 'Fast'};
            app.PlaybackspeedKnob.ItemsData = [5 1 0.1];
            app.PlaybackspeedKnob.ValueChangedFcn = createCallbackFcn(app, @PlaybackspeedKnobValueChanged, true);
            app.PlaybackspeedKnob.Value = 1;
            app.QATab = uitab(app.TabGroup);
            app.QATab.Title = 'QA';
            app.AutoRejectButton = uibutton(app.QATab, 'push');
            app.AutoRejectButton.ButtonPushedFcn = createCallbackFcn(app, @AutoRejectButtonPushed, true);
            app.AutoRejectButton.Text = 'Detect noise';
            app.NoisedetectionmethodLabel = uilabel(app.QATab);
            app.NoisedetectionmethodLabel.Text = 'Noise detection method';
            app.AutoRejectMethodDropDown = uidropdown(app.QATab);
            app.AutoRejectMethodDropDown.Items = {'Confidence interval x Noise detection threshold', 'STD x Noise detection threshold', 'Max amplitude(> Noise detection threshold)', 'Mean amplitude', 'Max peak2peak amplitude'};
            app.AutoRejectMethodDropDown.ItemsData = {'confidence_interval', 'nstd', 'max_amplitude', 'mean_amplitude', 'max_peak2peak'};
            app.AutoRejectMethodDropDown.Tooltip = {'Noise detection method'};
            app.AutoRejectMethodDropDown.Value = 'confidence_interval';
            app.RespdetectthrLabel_2 = uilabel(app.QATab);
            app.RespdetectthrLabel_2.Tooltip = {''};
            app.RespdetectthrLabel_2.Text = 'Noise detection threshold';
            app.AutoRejectStdsEditField = uieditfield(app.QATab, 'numeric');
            app.AutoRejectStdsEditField.Tooltip = {'Noise detection threshold level - Adjust the value until detection accuracy cannot be improved further.  For the ''Max amplitude'' detection method, use this field to  set the max allowed amplitude.'};
            app.AutoRejectStdsEditField.Value = 3;
            app.ResponseTab = uitab(app.TabGroup);
            app.ResponseTab.Title = 'Response';
            app.TabGroup2 = uitabgroup(app.ResponseTab);
            app.DetectionTab = uitab(app.TabGroup2);
            app.DetectionTab.Title = 'Detection';
            app.DetectResponseFeaturesButton = uibutton(app.DetectionTab, 'push');
            app.DetectResponseFeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @DetectResponseFeaturesButtonPushed, true);
            app.DetectResponseFeaturesButton.Text = 'Detect features';
            app.RespdetectthrLabel = uilabel(app.DetectionTab);
            app.RespdetectthrLabel.Tooltip = {''};
            app.RespdetectthrLabel.Text = 'Response detection threshold';
            app.ResponseStdsEditField = uieditfield(app.DetectionTab, 'numeric');
            app.ResponseStdsEditField.Limits = [1 Inf];
            app.ResponseStdsEditField.Tooltip = {'Response detection threshold level - Adjust the value until detection accuracy cannot be improved further. NOTE: unrelated to EP amplitude threshold.'};
            app.ResponseStdsEditField.Value = 15;
            app.ChangetimedetectionLabel_2 = uilabel(app.DetectionTab);
            app.ChangetimedetectionLabel_2.Text = 'EP presence detection';
            app.AutoPresenceDetectionMethodDropDown = uidropdown(app.DetectionTab);
            app.AutoPresenceDetectionMethodDropDown.Items = {'findchangepts', 'mep_classifier'};
            app.AutoPresenceDetectionMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @AutoPresenceDetectionMethodDropDownValueChanged, true);
            app.AutoPresenceDetectionMethodDropDown.Tooltip = {'Method used to automatically detect whether a signal contains a response.'};
            app.AutoPresenceDetectionMethodDropDown.Value = 'findchangepts';
            app.ChangetimedetectionLabel = uilabel(app.DetectionTab);
            app.ChangetimedetectionLabel.Text = 'Change time detection';
            app.AutoChangeTimeDetectionMethodDropDown = uidropdown(app.DetectionTab);
            app.AutoChangeTimeDetectionMethodDropDown.Items = {'findchangepts', 'mep_classifier'};
            app.AutoChangeTimeDetectionMethodDropDown.Tooltip = {'The method used to automatically detect when there is a notable change in signal, e.g. start ans stop of EP'};
            app.AutoChangeTimeDetectionMethodDropDown.Value = 'findchangepts';
            app.AutoDetectionCurrentEpochOnlyCheckBox = uicheckbox(app.DetectionTab);
            app.AutoDetectionCurrentEpochOnlyCheckBox.Tooltip = {'Select to limit detection to only the current epoch'};
            app.AutoDetectionCurrentEpochOnlyCheckBox.Text = 'Current epoch only';
            app.AutoDetectionCurrentEpochOnlyCheckBox.Value = true;
            app.ClassifierDropDownLabel = uilabel(app.DetectionTab);
            app.ClassifierDropDownLabel.Text = 'Classifier';
            app.MEPClassifierDropDown = uidropdown(app.DetectionTab);
            app.MEPClassifierDropDown.Items = {'default'};
            app.MEPClassifierDropDown.DropDownOpeningFcn = createCallbackFcn(app, @MEPClassifierDropDownOpening, true);
            app.MEPClassifierDropDown.Value = 'default';
            app.DetectResponseFeaturesStopButton = uibutton(app.DetectionTab, 'push');
            app.DetectResponseFeaturesStopButton.ButtonPushedFcn = createCallbackFcn(app, @DetectResponseFeaturesStopButtonPushed, true);
            app.DetectResponseFeaturesStopButton.Enable = 'off';
            app.DetectResponseFeaturesStopButton.Text = 'Stop';
            app.ResponseFeatureDetectionMetricsButton = uibutton(app.DetectionTab, 'push');
            app.ResponseFeatureDetectionMetricsButton.ButtonPushedFcn = createCallbackFcn(app, @ResponseFeatureDetectionMetricsButtonPushed, true);
            app.ResponseFeatureDetectionMetricsButton.Tooltip = {'Accuracy etc of  response feature detection using the last run methods/settings. Currently the result is exported to base workspace.'};
            app.ResponseFeatureDetectionMetricsButton.Text = 'Metrics';
            app.ResponseNormalisationTab = uitab(app.TabGroup2);
            app.ResponseNormalisationTab.Tooltip = {'Response nomalisation'};
            app.ResponseNormalisationTab.Title = 'Norm';
            app.UpdatewithsourcedatasetPanel = uipanel(app.ResponseNormalisationTab);
            app.UpdatewithsourcedatasetPanel.Title = 'Update with source dataset';
            app.ResponseNormalisationSourcedatasetButton = uibutton(app.UpdatewithsourcedatasetPanel, 'push');
            app.ResponseNormalisationSourcedatasetButton.ButtonPushedFcn = createCallbackFcn(app, @ResponseNormalisationSourcedatasetButtonPushed, true);
            app.ResponseNormalisationSourcedatasetButton.Text = 'Source dataset';
            app.TypeLabel = uilabel(app.UpdatewithsourcedatasetPanel);
            app.TypeLabel.Text = 'Type';
            app.ResponseNormalisationTypeDropDown = uidropdown(app.UpdatewithsourcedatasetPanel);
            app.ResponseNormalisationTypeDropDown.Items = {'Select'};
            app.ResponseNormalisationTypeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ResponseNormalisationTypeDropDownOpening, true);
            app.ResponseNormalisationTypeDropDown.Value = 'Select';
            app.MVCrmsTextAreaLabel = uilabel(app.ResponseNormalisationTab);
            app.MVCrmsTextAreaLabel.Text = 'MVC rms';
            app.ResponseNormalisationMVCrmsTextArea = uitextarea(app.ResponseNormalisationTab);
            app.ResponseNormalisationMVCrmsTextArea.ValueChangedFcn = createCallbackFcn(app, @ResponseNormalisationMVCrmsTextAreaValueChanged, true);
            app.ResponseNormalisationMVCrmsTextArea.Tooltip = {'Max rms of MVC. Edit to change manually. (Comma/space separated values where entry is for corresponding channel in order)'};
            app.Maxrespp2pLabel = uilabel(app.ResponseNormalisationTab);
            app.Maxrespp2pLabel.Text = 'Max resp. p2p';
            app.ResponseNormalisationMaxp2pTextArea = uitextarea(app.ResponseNormalisationTab);
            app.ResponseNormalisationMaxp2pTextArea.ValueChangedFcn = createCallbackFcn(app, @ResponseNormalisationMaxp2pTextAreaValueChanged, true);
            app.ResponseNormalisationMaxp2pTextArea.Tooltip = {'Max response peak2peak, e.g max peripheral stim response. Edit to change manually. (Comma/space separated values where entry is for corresponding channel in order)'};
            app.Datasetmustbepreprocessedfortypemaxresppeak2peakLabel = uilabel(app.ResponseNormalisationTab);
            app.Datasetmustbepreprocessedfortypemaxresppeak2peakLabel.Tooltip = {'Dataset must be preprocessed for type=max resp peak2peak...'};
            app.Datasetmustbepreprocessedfortypemaxresppeak2peakLabel.Text = 'Dataset must be preprocessed for type=max resp peak2peak';
            app.StimcodetointenLabel = uilabel(app.ResponseNormalisationTab);
            app.StimcodetointenLabel.Text = 'Stim. code to inten.';
            app.StimcodetointenEditField = uieditfield(app.ResponseNormalisationTab, 'text');
            app.StimcodetointenEditField.ValueChangedFcn = createCallbackFcn(app, @StimcodetointenEditFieldValueChanged, true);
            app.StimcodetointenEditField.Tooltip = {'Stimulus code to intensity comversion factor. E.g. RMT of the target muscle. (Square bracket surrounded,  comma/space separated values where entry is for corresponding channel in order)'};
            app.PlotResponseTab = uitab(app.TabGroup2);
            app.PlotResponseTab.Title = 'Plot';
            app.OpenRecruitmentCurveDlgButton = uibutton(app.PlotResponseTab, 'push');
            app.OpenRecruitmentCurveDlgButton.ButtonPushedFcn = createCallbackFcn(app, @OpenRecruitmentCurveDlgButtonPushed, true);
            app.OpenRecruitmentCurveDlgButton.Tooltip = {'Detect features before using this button to plot recruitment curve.'};
            app.OpenRecruitmentCurveDlgButton.Text = 'Recruitment curve';
            app.OpenAverageDlgButton = uibutton(app.PlotResponseTab, 'push');
            app.OpenAverageDlgButton.ButtonPushedFcn = createCallbackFcn(app, @OpenAverageDlgButtonPushed, true);
            app.OpenAverageDlgButton.Tooltip = {'Average/Evoked potential'};
            app.OpenAverageDlgButton.Text = 'Average';
            app.LabelTab = uitab(app.TabGroup);
            app.LabelTab.Title = 'Label';
            app.ExportdataPanel = uipanel(app.LabelTab);
            app.ExportdataPanel.Title = 'Export data';
            app.ExcllabelsEditFieldLabel = uilabel(app.ExportdataPanel);
            app.ExcllabelsEditFieldLabel.Text = 'Excl. labels';
            app.LabelExportExcludeLabelsEditField = uieditfield(app.ExportdataPanel, 'text');
            app.LabelExportExcludeLabelsEditField.Tooltip = {'Comma separated list of labels to exclude. To exclude a label, enter either the start or the stop label name. E.g to exclude pre_stimulus label, enter either   {''pre_stimulus_start''} or {''pre_stimulus_stop''}.'};
            app.LabelExportExcludeLabelsEditField.Value = 'pre_stimulus_start';
            app.MinsegmentsamplesLabel = uilabel(app.ExportdataPanel);
            app.MinsegmentsamplesLabel.Text = 'Min  segment samples';
            app.LabelExportMinSegmentSampleLenEditField = uieditfield(app.ExportdataPanel, 'numeric');
            app.LabelExportMinSegmentSampleLenEditField.Tooltip = {'The minimum length of samples in a label segment for a label to be considered included'};
            app.LabelExportMinSegmentSampleLenEditField.Value = 9;
            app.LabelExportButton = uibutton(app.ExportdataPanel, 'push');
            app.LabelExportButton.ButtonPushedFcn = createCallbackFcn(app, @LabelExportButtonPushed, true);
            app.LabelExportButton.Text = 'Export';
            app.CopyPanel = uipanel(app.LabelTab);
            app.CopyPanel.Title = 'Copy';
            app.LabelTimesFromeResponseButton = uibutton(app.CopyPanel, 'push');
            app.LabelTimesFromeResponseButton.ButtonPushedFcn = createCallbackFcn(app, @LabelTimesFromeResponseButtonPushed, true);
            app.LabelTimesFromeResponseButton.Tooltip = {'Copy the label times from the effective times set for the response. Labels that are not related to the reponse are not touched.'};
            app.LabelTimesFromeResponseButton.Text = 'From response';
            app.GuessLabelTimesButton = uibutton(app.CopyPanel, 'push');
            app.GuessLabelTimesButton.ButtonPushedFcn = createCallbackFcn(app, @GuessLabelTimesButtonPushed, true);
            app.GuessLabelTimesButton.Tooltip = {'Guess the time of all labels.'};
            app.GuessLabelTimesButton.Text = 'Guess';
            app.LabelTimesFromDatasetButton = uibutton(app.CopyPanel, 'push');
            app.LabelTimesFromDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @LabelTimesFromDatasetButtonPushed, true);
            app.LabelTimesFromDatasetButton.Tooltip = {'Copy all label times from another dataset'};
            app.LabelTimesFromDatasetButton.Text = 'From dataset';
            app.NextEpochButton = uibutton(app.RightPanel, 'push');
            app.NextEpochButton.ButtonPushedFcn = createCallbackFcn(app, @NextEpochButtonPushed, true);
            app.NextEpochButton.Tooltip = {'Next epoch'};
            app.NextEpochButton.Text = '>';
            app.PreviousEpochButton = uibutton(app.RightPanel, 'push');
            app.PreviousEpochButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousEpochButtonPushed, true);
            app.PreviousEpochButton.Tooltip = {'Previous epoch'};
            app.PreviousEpochButton.Text = '<';
            % Show the figure after all components are created
            app.EPRecorderEpochLabUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = eprecorder_epoch_lab(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EPRecorderEpochLabUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EPRecorderEpochLabUIFigure)
        end
    end
end