classdef eprecorder_tescs_parameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TESCSparametersidentificationUIFigure  matlab.ui.Figure
        LeftPanel                       matlab.ui.container.Panel
        RightPanel                      matlab.ui.container.Panel
        SecondstimulusDropDown          matlab.ui.control.DropDown
        SecondstimulusLabel             matlab.ui.control.Label
        FirststimulusDropDown           matlab.ui.control.DropDown
        FirststimulusDropDownLabel      matlab.ui.control.Label
        PRMcheckformissingfirstresponseCheckBox  matlab.ui.control.CheckBox
        PRMcheckCheckBox                matlab.ui.control.CheckBox
        AutopreprocessdatasetsCheckBox  matlab.ui.control.CheckBox
        IdentifyButton                  matlab.ui.control.Button
        ResponsefeatureDropDown         matlab.ui.control.DropDown
        ResponsefeatureDropDownLabel    matlab.ui.control.Label
        RestingMotorThresholdEditField  matlab.ui.control.NumericEditField
        RMTEditFieldLabel               matlab.ui.control.Label
        StimuluscodesEditField          matlab.ui.control.EditField
        StimuluscodesEditFieldLabel     matlab.ui.control.Label
        ChannelindicesEditField         matlab.ui.control.EditField
        ChannelindicesEditFieldLabel    matlab.ui.control.Label
        DatasetindicesEditField         matlab.ui.control.EditField
        DatasetindicesEditFieldLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        datasets={}; % A cell array of datasets.
        datasetLabels={};% Labels corresponding to the datasets
        labels={'','C3-C4','C4-C5','C5-C6','C6-C7','C7-T1'}; % All labels.

        datasetIndices=[];% The indices of the datasets and their required order. 
    end
    
    methods (Access = private)
        function init(app)
            % Initialise data

            % 0. Placeholder for dataset labels
            dlabels={};
            for n=1:length(app.datasets)
                dlabels{n}='';
            end
            app.datasetLabels=dlabels;

            % 1. Get initial dataset indexes
            app.datasetIndices=1:length(app.datasets);
            app.DatasetindicesEditField.Value=mat2str(app.datasetIndices);

            % 2. Get the default channel indices
            channelIndices=app.commonChannelIndices();
            app.ChannelindicesEditField.Value=mat2str(channelIndices);

            % 3. Get the default stimulusCodes
            stimulusCodes=app.commonStimulusCodes();
            app.StimuluscodesEditField.Value=mat2str(stimulusCodes);  

            % 4. Get the epoch stimulus numbers
            stimulusNumbers=app.commonStimulusNumbers();
            stimulusNumbersStr=string(stimulusNumbers);
            app.FirststimulusDropDown.Items=stimulusNumbersStr;
            app.FirststimulusDropDown.ItemsData=stimulusNumbers;
            app.FirststimulusDropDown.Value=stimulusNumbers(1);
            
            app.SecondstimulusDropDown.Items=stimulusNumbersStr;
            app.SecondstimulusDropDown.ItemsData=stimulusNumbers;
            app.SecondstimulusDropDown.Value=stimulusNumbers(1);
            if length(stimulusNumbers)>1
                app.SecondstimulusDropDown.Value=stimulusNumbers(2);
            end


            % 5. Set status of auto preprocessing
            if app.isAllContinuous()
                app.AutopreprocessdatasetsCheckBox.Value=1;
            end
        end

        function stimulusCodes=commonStimulusCodes(app)
            % Get the stimulus codes that are common to all datasets. Works
            % for both epoched and unepoched dataset.
            % [OUTPUT]
            % stimulusCodes array<double>: Stimulus codes common to all
            %   datasets.
            stimulusCodes=[];
            for n=1:length(app.datasets)
                if eprecorder_has_epoch(app.datasets{n})
                    stimulusCodesTemp=eprecorder_get_epoch_stimulus_code(app.datasets{n});
                else
                    [~,stimulusCodesTemp]=eprecorder_triggers(app.datasets{n});
                end
                if(n==1)
                    stimulusCodes=stimulusCodesTemp;
                end
                stimulusCodes=intersect(stimulusCodes, ...
                    stimulusCodesTemp,'sorted');
            end
        end
        function stimulusNumbers=commonStimulusNumbers(app)
            % Get the epoch stimulus numbers that are common to all
            % datasets.
            % [OUTPUT]
            % stimulusNumbers array<int>: Stimulus numbers common to all
            %   datasets.
            stimulusNumbers=[];
            for n=1:length(app.datasets)
                stimulusNumbersTemp=eprecorder_stimulus_numbers(app.datasets{n});
                
                if(n==1)
                    stimulusNumbers=stimulusNumbersTemp;
                end
                stimulusNumbers=intersect(stimulusNumbers, ...
                    stimulusNumbersTemp,'sorted');
            end
        end
        function [iscommon,mismatches]= isCommonStimulusCodes(app,stimulusCodes)
            % Check if the given stimulus codes are common to all datsets.
            % [INPUT]
            % stimulusCodes array<double>: The stimulus codes to check.
            % [OUTPUT]
            % iscommon bool: True if the stimulus codes are common to all
            %   datasets.
            % mismatches: The stimulus codes from the input that made the
            %   stimulus codes uncommon.

            iscommon=false;
            common=app.commonStimulusCodes();
            mismatches=[];
            for n=1:length(stimulusCodes)
                if ~ismember(stimulusCodes(n),common)
                    mismatches(end+1)=stimulusCodes(n);
                end
            end

            if isempty(mismatches)
                iscommon=true;
            end
        end
        function channelIndices=commonChannelIndices(app)
            % Get the channel indices that are common to all datasets.
            % [OUTPUT]
            % channelIndices array<double>: Channel indices common to all
            %   datasets.
            channelIndices=[];
            for n=1:length(app.datasets)
                indices=1:length(app.datasets{n}.channelNames);
                if(n==1)
                    channelIndices=indices;
                end
                channelIndices=intersect(channelIndices,indices,'sorted');
            end
        end
        function [iscommon,mismatches]=isCommonChannelIndices(app,indices)
            % Check if the given channel indices are common to all datsets.
            % [INPUT]
            % indices array<double>: The channel indices to check.
            % [OUTPUT]
            % iscommon bool: True if the channels indices are common to all
            %   datasets.
            % mismatches: The channel indices from the input that made the
            %   channel indices uncommon.
            iscommon=false;
            common=app.commonChannelIndices();
            mismatches=[];
            for n=1:length(indices)
                if ~ismember(indices(n),common)
                    mismatches(end+1)=indices(n);
                end
            end

            if isempty(mismatches)
                iscommon=true;
            end
        end
        function isepoched=isAllEpoched(app)
            % Check if all datasets are epoched;
            % [OUTPUT]
            % isepoched boolean: True if all datasets are epoched.

            isepoched=true;
            for n=1:length(app.datasets)
                if ~eprecorder_has_epoch(app.datasets{n})
                    isepoched=false;
                    break;
                end
            end

        end
        function iscontinuous=isAllContinuous(app)
            % Check if all datasets are continuous;
            % [OUTPUT]
            % isepoched boolean: True if all datasets are continuous.

            iscontinuous=true;
            for n=1:length(app.datasets)
                if eprecorder_has_epoch(app.datasets{n})
                    iscontinuous=false;
                    break;
                end
            end
        end
        function listDatasets(app)
            % Build view to list datasets.
            ndatasets=length(app.datasets);

            
            ngridrows=ndatasets;
            ngridcols=2;
            leftPanelGrid=uigridlayout(app.LeftPanel,[ngridrows,ngridcols]);
            leftPanelGrid.RowHeight=repmat(22,1,ngridrows);
            leftPanelGrid.ColumnWidth={'3x','1x'};
            leftPanelGrid.Padding=[10,10,10,10];
            leftPanelGrid.ColumnSpacing=1;
            leftPanelGrid.RowSpacing=10;
            leftPanelGrid.Scrollable='on';

            for n=1:ndatasets
                dataset_label='';
                if(length(app.datasetLabels))>=n
                    dataset_label=app.datasetLabels{n};
                end

                enable='off';
                if(ismember(n,app.datasetIndices))
                    enable='on';
                end

                epoched="CONTINUOUS";
                colour=[0,0,0];
                if eprecorder_has_epoch(app.datasets{n})
                    epoched="EPOCHED";
                    colour=[0.1,0.8,0.3];
                end
                
                uilabel(leftPanelGrid,"Text",sprintf(['Dataset index ' ...
                    '%d (%s) - %s'],n,app.datasets{n}.name,epoched), ...
                    "FontColor",colour);
                uidropdown(leftPanelGrid,"Value",dataset_label,"Items", ...
                    app.labels,"UserData",n, ...
                    "enable",enable,...
                    "ValueChangedFcn",@app.onDatasetLabelChange);

            end
            
        end

    function onDatasetLabelChange(app,source,event)
            % Respond to dataset label change
            datasetIndex=source.UserData;
            app.datasetLabels{datasetIndex}=source.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, datasets)
            app.datasets=datasets;
            app.init();
            app.listDatasets();
        end

        % Button pushed function: IdentifyButton
        function IdentifyButtonPushed(app, event)

            % Read in data
            stimulusCodes=str2num(app.StimuluscodesEditField.Value);
            channelIndices=str2num(app.ChannelindicesEditField.Value);           
            feature=app.ResponsefeatureDropDown.Value;
            RMT=app.RestingMotorThresholdEditField.Value;
            firstStimulusNumber=app.FirststimulusDropDown.Value;
            secondStimulusNumber=app.SecondstimulusDropDown.Value;
            PRMCheck=logical(app.PRMcheckCheckBox.Value);
            PRMCheckOnMissingFirstRespons=logical(app.PRMcheckformissingfirstresponseCheckBox.Value);

            % Vakidate that dayasets are either all continuous or all
            % epoched
            if ~app.isAllContinuous() && ~app.isAllEpoched()
                warndlg(['Datasets should either be all continuous or ' ...
                    'all epoched'],'Invalid data');
                return;
            end

            % Validate stimulus code
            commonStimulusCodes=app.commonStimulusCodes();
            [iscommon,misMatchedCodes]=app.isCommonStimulusCodes(stimulusCodes);
            if ~iscommon
                msg=[sprintf(" Stimulus codes, %s are not in all dataset. " + ...
                    "",mat2str(misMatchedCodes)) sprintf("The common stimulus " + ...
                    "are: %s",mat2str(commonStimulusCodes))];
                warndlg(msg,'Invalid data');
                return;
            end

            % Validate channel indices
            commonChannelIndices=app.commonChannelIndices();
            [iscommon,misMatchedIndices]=app.isCommonChannelIndices( ...
                channelIndices);
            if ~iscommon
                msg=[sprintf("Channel indices, %s are not in all dataset. " + ...
                    "",mat2str(misMatchedIndices)) sprintf("The common indices " + ...
                    "are: %s",mat2str(commonChannelIndices))];
                warndlg(msg,'Invalid data');
                return;
            end

            % Validate dataset indices
            if any(app.datasetIndices>length(app.datasets))
                warndlg('Invalid dataset indice/s','Invalid data')
                return;
            end


            % Continue with identification
            tescs=eprecorder_tescs;
            tescs.responseFeature=feature;
            tescs.stimulusCodes=stimulusCodes;
            tescs.stimulusCodeLabels={};
            tescs.stimulusRestingMotorThreshod=RMT;
            tescs.chanNums=channelIndices;
            tescs.datasetLabels=app.datasetLabels(app.datasetIndices);
            tescs.PRMFirstStimulusNumber=firstStimulusNumber;
            tescs.PRMSecondStimulusNumber=secondStimulusNumber;
            tescs.PRMCheck=PRMCheck;
            tescs.PRMCheckOnMissingFirstResponse=PRMCheckOnMissingFirstRespons;


            try
                app.IdentifyButton.Text='Working...';
                app.IdentifyButton.Enable='off';
                drawnow;
                if app.AutopreprocessdatasetsCheckBox.Value
                    tescs.autoPreprocessAndIdentify(app.datasets(app.datasetIndices));
                else
                    tescs.identify(app.datasets(app.datasetIndices));
                end
            catch ME
                app.IdentifyButton.Text='Identify';
                app.IdentifyButton.Enable='on';
                rethrow(ME);
            end
            app.IdentifyButton.Text='Identify';
            app.IdentifyButton.Enable='on';


        end

        % Value changed function: DatasetindicesEditField
        function DatasetindicesEditFieldValueChanged(app, event)
            
            newIndices= str2num(app.DatasetindicesEditField.Value);  

            %
            app.datasetIndices=newIndices;
            app.listDatasets();
        end

        % Value changed function: PRMcheckCheckBox
        function PRMcheckCheckBoxValueChanged(app, event)
            value = app.PRMcheckCheckBox.Value;
            if(value)
                app.SecondstimulusDropDown.Enable="on";
                app.PRMcheckformissingfirstresponseCheckBox.Enable="on";
            else
                app.SecondstimulusDropDown.Enable="off";
                app.PRMcheckformissingfirstresponseCheckBox.Enable="off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TESCSparametersidentificationUIFigure = uifigure('Visible', 'off');
            app.TESCSparametersidentificationUIFigure.Name = 'TESCS parameters identification';
            app.RightPanel = uipanel(app.TESCSparametersidentificationUIFigure);
            app.RightPanel.Title = 'Settings';
            app.DatasetindicesEditFieldLabel = uilabel(app.RightPanel);
            app.DatasetindicesEditFieldLabel.Text = 'Dataset indices';
            app.DatasetindicesEditField = uieditfield(app.RightPanel, 'text');
            app.DatasetindicesEditField.ValueChangedFcn = createCallbackFcn(app, @DatasetindicesEditFieldValueChanged, true);
            app.DatasetindicesEditField.Tooltip = {'Indices of the dataset on the left to be used. Order the indices as desired.'};
            app.DatasetindicesEditField.Value = '[]';
            app.ChannelindicesEditFieldLabel = uilabel(app.RightPanel);
            app.ChannelindicesEditFieldLabel.Text = 'Channel indices';
            app.ChannelindicesEditField = uieditfield(app.RightPanel, 'text');
            app.ChannelindicesEditField.Tooltip = {'The indices of the channel data to be considered. The first channel data corresponds to index 1.'};
            app.ChannelindicesEditField.Value = '[]';
            app.StimuluscodesEditFieldLabel = uilabel(app.RightPanel);
            app.StimuluscodesEditFieldLabel.Text = 'Stimulus codes';
            app.StimuluscodesEditField = uieditfield(app.RightPanel, 'text');
            app.StimuluscodesEditField.Tooltip = {'Event codes corresponding to stimulation intensities.'};
            app.StimuluscodesEditField.Value = '[]';
            app.RMTEditFieldLabel = uilabel(app.RightPanel);
            app.RMTEditFieldLabel.Tooltip = {'Stimulus resting motor threshold'};
            app.RMTEditFieldLabel.Text = 'RMT';
            app.RestingMotorThresholdEditField = uieditfield(app.RightPanel, 'numeric');
            app.RestingMotorThresholdEditField.Limits = [1 500];
            app.RestingMotorThresholdEditField.Tooltip = {'Stimulus resting motor threshold.'};
            app.RestingMotorThresholdEditField.Value = 1;
            app.ResponsefeatureDropDownLabel = uilabel(app.RightPanel);
            app.ResponsefeatureDropDownLabel.Text = 'Response feature';
            app.ResponsefeatureDropDown = uidropdown(app.RightPanel);
            app.ResponsefeatureDropDown.Items = {'peak2peak', 'area'};
            app.ResponsefeatureDropDown.Value = 'peak2peak';
            app.IdentifyButton = uibutton(app.RightPanel, 'push');
            app.IdentifyButton.ButtonPushedFcn = createCallbackFcn(app, @IdentifyButtonPushed, true);
            app.IdentifyButton.Tooltip = {'Plot stimulation parameters.'};
            app.IdentifyButton.Text = 'Identify';
            app.AutopreprocessdatasetsCheckBox = uicheckbox(app.RightPanel);
            app.AutopreprocessdatasetsCheckBox.Enable = 'off';
            app.AutopreprocessdatasetsCheckBox.Tooltip = {'A readonly field which indicates whether the set of datasets are eligible for automatic preprocessing prior to identification of stimulus parameters.'};
            app.AutopreprocessdatasetsCheckBox.Text = 'Auto preprocess datasets';
            app.PRMcheckCheckBox = uicheckbox(app.RightPanel);
            app.PRMcheckCheckBox.ValueChangedFcn = createCallbackFcn(app, @PRMcheckCheckBoxValueChanged, true);
            app.PRMcheckCheckBox.Tooltip = {'If checked, epochs with non-PRM(Posterior root-muscle) responses will not be considered'};
            app.PRMcheckCheckBox.Text = 'PRM check';
            app.PRMcheckCheckBox.Value = true;
            app.PRMcheckformissingfirstresponseCheckBox = uicheckbox(app.RightPanel);
            app.PRMcheckformissingfirstresponseCheckBox.Tooltip = {'When selected, PRM(Posterior root-muscle) check will be performed even if response for the first stimulus is absent. This parameters is irrelevant when the PRM check is disabled.'};
            app.PRMcheckformissingfirstresponseCheckBox.Text = 'PRM check for missing first response';
            app.PRMcheckformissingfirstresponseCheckBox.Value = true;
            app.FirststimulusDropDownLabel = uilabel(app.RightPanel);
            app.FirststimulusDropDownLabel.Text = 'First stimulus #';
            app.FirststimulusDropDown = uidropdown(app.RightPanel);
            app.FirststimulusDropDown.Items = {'1', '2'};
            app.FirststimulusDropDown.ItemsData = {'1', '2'};
            app.FirststimulusDropDown.Tooltip = {'The number for the stimulus within an epoch to be used as the first.'};
            app.FirststimulusDropDown.Value = '1';
            app.SecondstimulusLabel = uilabel(app.RightPanel);
            app.SecondstimulusLabel.Text = 'Second stimulus #';
            app.SecondstimulusDropDown = uidropdown(app.RightPanel);
            app.SecondstimulusDropDown.Items = {'1', '2'};
            app.SecondstimulusDropDown.ItemsData = {'1', '2'};
            app.SecondstimulusDropDown.Tooltip = {'The number for the stimulus within an epoch to be used as the second.'};
            app.SecondstimulusDropDown.Value = '2';
            app.LeftPanel = uipanel(app.TESCSparametersidentificationUIFigure);
            app.LeftPanel.Title = 'Datasets and corresponding spinal levels';
            % Show the figure after all components are created
            app.TESCSparametersidentificationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = eprecorder_tescs_parameters(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TESCSparametersidentificationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TESCSparametersidentificationUIFigure)
        end
    end
end